classdef EEG_signal_feature_processing_software < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ImageDisplayPanel               matlab.ui.container.Panel
        BackButton_2                    matlab.ui.control.Button
        ImageUIAxes                     matlab.ui.control.UIAxes
        ExportButton                    matlab.ui.control.Button
        ClassifiersPanel                matlab.ui.container.Panel
        BackButton_4                    matlab.ui.control.Button
        DeleteButton_4                  matlab.ui.control.Button
        AddButton_3                     matlab.ui.control.Button
        AllClassifiersListBox           matlab.ui.control.ListBox
        AllClassifiersLabel             matlab.ui.control.Label
        LoadPanel                       matlab.ui.container.Panel
        FstopHzEditField                matlab.ui.control.NumericEditField
        FstopHzEditFieldLabel           matlab.ui.control.Label
        FpassHzEditField                matlab.ui.control.NumericEditField
        FpassHzEditFieldLabel           matlab.ui.control.Label
        BandpassFilterLabel             matlab.ui.control.Label
        NotchFilterLabel                matlab.ui.control.Label
        LoadFolderButton                matlab.ui.control.Button
        CrossValidationFoldEditField    matlab.ui.control.EditField
        CrossValidationFoldEditFieldLabel  matlab.ui.control.Label
        FsEditField                     matlab.ui.control.NumericEditField
        OriginalSamplingFrequencyHzLabel  matlab.ui.control.Label
        OrderDropDown                   matlab.ui.control.DropDown
        OrderDropDownLabel              matlab.ui.control.Label
        BPCheckBox                      matlab.ui.control.CheckBox
        NotchFreqDropDown               matlab.ui.control.DropDown
        NotchFrequencyLabel             matlab.ui.control.Label
        NotchCheckBox                   matlab.ui.control.CheckBox
        StartButton_2                   matlab.ui.control.Button
        LoadzipButton                   matlab.ui.control.Button
        LoadmatButton                   matlab.ui.control.Button
        BackButton                      matlab.ui.control.Button
        FeatureExtractionMethodsPanel   matlab.ui.container.Panel
        BackButton_3                    matlab.ui.control.Button
        DeleteButton_3                  matlab.ui.control.Button
        AddFeature                      matlab.ui.control.Button
        FeatureExtractionMethodListBox  matlab.ui.control.ListBox
        FeatureExtractionMethodsLabel   matlab.ui.control.Label
        LoadFileButton                  matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        MapButton                       matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        TrainingSettingsTab             matlab.ui.container.Tab
        MoreButton_2                    matlab.ui.control.Button
        MoreButton                      matlab.ui.control.Button
        ParallelComputingSwitch         matlab.ui.control.Switch
        ParallelComputingSwitchLabel    matlab.ui.control.Label
        FeatureSelectionRateEditField   matlab.ui.control.NumericEditField
        FeatureSelectionRateLabel       matlab.ui.control.Label
        ClassifierDropDown              matlab.ui.control.DropDown
        ClassifierDropDownLabel         matlab.ui.control.Label
        FeatureSelectionMethodDropDown  matlab.ui.control.DropDown
        FeatureSelectionMethodLabel     matlab.ui.control.Label
        FeatureSelectionOptionDropDown  matlab.ui.control.DropDown
        FeatureSelectionOptionDropDownLabel  matlab.ui.control.Label
        FeatureExtractionMethodDropDown_2  matlab.ui.control.DropDown
        FeatureExtractionMethodDropDown_2Label  matlab.ui.control.Label
        TimeFrequencySegmentationStrategiesTab  matlab.ui.container.Tab
        FilterOrderEditField            matlab.ui.control.NumericEditField
        FilterOrderEditFieldLabel       matlab.ui.control.Label
        WindowFunctionDropDown          matlab.ui.control.DropDown
        WindowFunctionDropDownLabel     matlab.ui.control.Label
        DeleteButton_2                  matlab.ui.control.Button
        AddButton_2                     matlab.ui.control.Button
        FrequencyRangeHzEditField       matlab.ui.control.EditField
        FrequencyRangeHzEditFieldLabel  matlab.ui.control.Label
        DeleteButton                    matlab.ui.control.Button
        AddButton                       matlab.ui.control.StateButton
        TimeRangesEditField             matlab.ui.control.EditField
        TimeRangesEditFieldLabel        matlab.ui.control.Label
        FrequencyRangeListHzListBox     matlab.ui.control.ListBox
        FrequencyRangeListHzLabel       matlab.ui.control.Label
        TimeRangeListsListBox           matlab.ui.control.ListBox
        TimeRangeListsLabel             matlab.ui.control.Label
        InformationDisplayTextArea      matlab.ui.control.TextArea
        InformationDisplayTextAreaLabel  matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = public)
       LoadedData     % 添加属性以存储加载的数据 % Description
        opts
         ResData
         ResultsData      
        FeatureMapping  % 特征映射表，将下拉菜单选项映射到特征选择方法
        FeatureSelectedMethodMapping  % 特征选择方法映射表
        ClassifierMapping
        WindowfunctionMapping      
    end

    properties (Access = private)
    SelectedFile = '';
    SelectedType = '';
    SelectedFeatures = {};
    SelectedClassifiers = {};
end
    
    methods (Access = public)
        
% 构建进度信息的函数
function message = buildProgressMessage(app)
    % 初始化消息字符串
    message = 'Feature Extraction Methods: ';
    
    % 遍历列表框中的所有项
    for i = 1:length(app.FeatureExtractionMethodListBox.Items)
        message = [message, app.FeatureExtractionMethodListBox.Items{i}];
        if i < length(app.FeatureExtractionMethodListBox.Items)
            message = [message, ', '];
        end
    end
end

% 构建进度信息的函数
function message = buildClassifiersProgressMessage(app)
    % 初始化消息字符串
    message = 'Classifiers: ';
    
    % 遍历列表框中的所有项
    for i = 1:length(app.AllClassifiersListBox.Items)
        message = [message, app.AllClassifiersListBox.Items{i}];
        if i < length(app.AllClassifiersListBox.Items)
            message = [message, ', '];
        end
    end
end


 function updateProgress(app, message)
            % 确保 currentText 是字符串数组
            currentText = app.InformationDisplayTextArea.Value;  
            if ischar(currentText)
                currentText = string(currentText); % 强制转换为字符串数组
            end
    
            % 确保 message 是字符串
            if ischar(message)
                message = string(message);
            end
    
            % 拼接新消息
            if isempty(currentText)
                currentText = message;
            else
                currentText = [currentText; message]; % 这里改为**竖向拼接**
            end
    
            % 更新进度文本框
            app.InformationDisplayTextArea.Value = currentText;
            drawnow; % 强制刷新界面
 end


function updateMapButtonState(app)
    if isempty(app.ResData)
        app.MapButton.Enable = 'off'; % 禁用按钮
    else
        app.MapButton.Enable = 'on'; % 启用按钮
    end
    drawnow; % 强制刷新界面
end

       % 辅助函数：更新阶段进度
function updateStageProgress(~, progressDlg, currentProgress, stageWeight, pct, msg)
    % 计算总进度
    totalProgress = currentProgress + stageWeight * pct;
    progressDlg.Value = totalProgress;
    progressDlg.Message = msg;
end


function updateClassifierDropDown(app)
    % 预定义分类器名称列表（下拉框显示内容与函数名相同）
    classifierNames = {
        'ANN'
        'Discr_LinearKernel'
        'Ensemble_AdaBoost'
        'KNN'
        'LSTM'
        'NaiveBayes'
        'SVM_GaussianKernel'
        'SVM_GaussianKernel_Auto'
        'SVM_LinearKernel'
        'Tree'
    };

    % 生成映射表（key = value）
    app.ClassifierMapping = containers.Map(classifierNames, classifierNames);

    % 更新下拉菜单选项
    app.ClassifierDropDown.Items = classifierNames;

    % 可选更新提示（调试用）
    % updateProgress(app, 'Classifier methods loaded from hardcoded list.');
end


function updateFeatureSelectionDropDown(app)
    % 预定义特征选择方法名称列表
    featureSelectionNames = {
        'Chi_SquareTest'
        'DecisionTree'
        'F_Score'
        'F_Test'
        'FeatureSelectionViaEigenvectorCentrality'
        'InfiniteFeatureSelection'
        'IterativeLocalSearch'
        'LaplacianScore'
        'LeastAbsoluteShrinkageandSelectionOperator'
        'LocalLearningbasedClusteringFeatureSelection'
        'MinimumRedundancyMaximumRelevance'
        'NeighborhoodComponentAnalysis'
        'Relieff'
    };

    % 生成映射表（key = value）
    app.FeatureSelectedMethodMapping = containers.Map(featureSelectionNames, featureSelectionNames);

    % 更新下拉菜单选项
    app.FeatureSelectionMethodDropDown.Items = featureSelectionNames;

    % 可选进度提示（可注释）
    % updateProgress(app, 'Feature selection methods loaded from hardcoded list.');
end

function updateWindowsDropDown(app)
               WindowfunctionNames = {
                        'Rectangular'
                        'Hamming'
                        'Chebwin'
                        'Blackman'
                        'Blackmanharris'
                        'Hanning'
                        'Gausswin'
                        };
                       
               % 生成映射表（key = value）
    app.WindowfunctionMapping = containers.Map(WindowfunctionNames, WindowfunctionNames);

     % 更新下拉菜单选项

    app.WindowFunctionDropDown.Items = WindowfunctionNames;
                

    % 可选进度提示（可注释）
    % updateProgress(app, 'Feature selection methods loaded from hardcoded list.');
end

function updateFeatureExtractionDropDown(app)
    % 预定义特征提取方法名称列表（与函数文件名一致）
    featureExtractionNames = {
        'ArithmeticMean'
        'DifferentialEntropy'
        'FirstDifference'
        'HjorthActivity'
        'HjorthComplexity'
        'HjorthMobility'
        'Kurtosis'
        'LogEnergyEntropy'
        'LogRootSumOfSequentialVariation'
        'Maximum'
        'MeanCurveLength'
        'MeanEnergy'
        'MeanTeagerEnergy'
        'Median'
        'Minimum'
        'NormalizedFirstDifference'
        'NormalizedSecondDifference'
        'RenyiEntropy'
        'SecondDifference'
        'ShannonEntropy'
        'Skewness'
        'StandardDeviation'
        'TsallisEntropy'
    };

    % 创建映射表（key = value）
    app.FeatureMapping = containers.Map(featureExtractionNames, featureExtractionNames);

    % 设置下拉菜单选项
    app.FeatureExtractionMethodDropDown_2.Items = featureExtractionNames;

    % 可选进度提示
    % updateProgress(app, 'Feature extraction methods loaded from hardcoded list.');
end

        
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FsEditField.Value = 250;
            app.UIFigure.Name = 'EEG-TFX';
            % Multi-scale Time-Frequency Segmentation Framework 
            % 应用程序启动时调用的函数
            updateClassifierDropDown(app);
            updateFeatureSelectionDropDown(app);
            updateFeatureExtractionDropDown(app);
            updateWindowsDropDown(app);
            % 获取当前运行的 MATLAB 文件的完整路径
    currentFilePath = mfilename('fullpath');
    
    % 获取文件所在的文件夹路径
    appPath = fileparts(currentFilePath);
    
    % 定义需要添加到路径中的文件夹
    foldersToAdd = {
        fullfile(appPath, 'Classifier'),
        fullfile(appPath, 'Feature Selection Method'),
        fullfile(appPath, 'Feature Extraction Method'),
        fullfile(appPath, 'Window Function'),
        fullfile(appPath, 'util'),
    };
    
    % 将这些文件夹添加到 MATLAB 的路径中
    for i = 1:length(foldersToAdd)
        folderPath = foldersToAdd{i};
        if exist(folderPath, 'dir') % 检查文件夹是否存在
            addpath(folderPath); % 添加到路径中
        else
            warning('文件夹不存在: %s', folderPath);
        end
    end
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)

    app.LoadPanel.Visible = 'on';


        end

        % Value changed function: AddButton
        function AddButtonValueChanged(app, event)

            % Add the value from the edit field to the listbox
            value = app.TimeRangesEditField.Value;
            if ~isempty(value)
                app.TimeRangeListsListBox.Items{end+1} = num2str(value);
                app.TimeRangesEditField.Value = ''; % Clear the edit field
            else
                uialert(app.UIFigure, 'Please enter a value to add.', 'Input Required');
            end

        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)

            [~,idx] = ismember(app.TimeRangeListsListBox.Value,app.TimeRangeListsListBox.Items);
            app.TimeRangeListsListBox.Items(idx) = [];


        end

        % Button pushed function: AddButton_2
        function AddButton_2Pushed(app, event)
          
            % Add the value from the edit field to the listbox
            value2 = app.FrequencyRangeHzEditField.Value;
            if ~isempty(value2)
                app.FrequencyRangeListHzListBox.Items{end+1} = num2str(value2);
                app.FrequencyRangeHzEditField.Value = ''; % Clear the edit field
            else
                uialert(app.UIFigure, 'Please enter a value to add.', 'Input Required');
            end
  
        end

        % Button pushed function: DeleteButton_2
        function DeleteButton_2Pushed(app, event)
            
            [~,idx] = ismember(app.FrequencyRangeListHzListBox.Value,app.FrequencyRangeListHzListBox.Items);
            app.FrequencyRangeListHzListBox.Items(idx) = [];


        end

        % Button pushed function: StartButton
        function StartButtonPushed2(app, event)
   % 打印 Switch 的值
switchValue = app.ParallelComputingSwitch.Value;

% 初始化 opts 结构体
opts = struct();
savedOpts = struct();
% Check if the switch is 'on' or 'off'
if strcmp(switchValue, 'On')
    opts.ParallelSwitch = 1;
     savedOpts.ParallelSwitch = 1;
      app.opts.ParallelSwitch = 1;
    updateProgress(app, 'Parallel pool opened.');
else 
    opts.ParallelSwitch = 0;
     savedOpts.ParallelSwitch = 0;
      app.opts.ParallelSwitch = 0;
    updateProgress(app, 'Parallel pool closed.');
end             
opts.Filterorder = app.FilterOrderEditField.Value; 
savedOpts.Filterorder = app.FilterOrderEditField.Value; 
app.opts.Filterorder = app.FilterOrderEditField.Value; 
selectedOption = app.FeatureSelectionOptionDropDown.Value;
opts.fs = 250;   
app.opts.fs = 250;   

% 根据选项执行不同功能
switch selectedOption
case 'No Feature Selection'
    % 初始化主进度条
    mainProgressDlg = uiprogressdlg(app.UIFigure, 'Title', 'Multi-File Processing Pipeline',...
                               'Message', 'Starting processing...',...
                               'Indeterminate', 'off');
    
    try
        % 判断输入类型（单个文件或文件夹）
        if isfolder(app.SelectedFile)
            % 处理文件夹情况
            matFiles = dir(fullfile(app.SelectedFile, '*.mat'));
            zipFiles = dir(fullfile(app.SelectedFile, '*.zip'));
            allFiles = [matFiles; zipFiles];
            numFiles = length(allFiles);
            
            if numFiles == 0
                error('No MAT or ZIP files found in the selected folder.');
            end
            
            fileType = 'folder';
        else
            % 处理单个文件情况
            [~,~,ext] = fileparts(app.SelectedFile);
            if strcmpi(ext, '.mat')
                allFiles.name = app.SelectedFile;
                fileType = 'single_mat';
            elseif strcmpi(ext, '.zip')
                allFiles.name = app.SelectedFile;
                fileType = 'single_zip';
            else
                error('Unsupported file type. Only .mat and .zip files are supported.');
            end
            numFiles = 1;
        end
        
        % 初始化结果存储结构
        allResults = struct('filename', {}, 'accuracy', {}, 'avgAccuracy', {}, 'classifiers', {});
        
        % 定义各阶段权重（特征提取40%，分类60%）
        stageWeights = [0.4, 0.6];
        
        % 循环处理每个文件
        for fileIdx = 1:numFiles
            if strcmp(fileType, 'folder')
                currentFile = allFiles(fileIdx).name;
                filePath = fullfile(app.SelectedFile, currentFile);
                [~,~,ext] = fileparts(currentFile);
                isMatFile = strcmpi(ext, '.mat');
                isZipFile = strcmpi(ext, '.zip');
            else
                filePath = app.SelectedFile;
                [~,currentFile,ext] = fileparts(filePath);
                currentFile = [currentFile ext];
                isMatFile = strcmpi(ext, '.mat');
                isZipFile = strcmpi(ext, '.zip');
            end
            
            % 更新主进度条
            mainProgressDlg.Value = (fileIdx-1)/numFiles;
            mainProgressDlg.Message = sprintf('Processing %d/%d: %s',...
                fileIdx, numFiles, currentFile);
            drawnow;
            
            % 为当前文件创建处理进度条
            fileProgressDlg = uiprogressdlg(app.UIFigure, 'Title', sprintf('Processing %s', currentFile),...
                                          'Message', 'Loading file...',...
                                          'Indeterminate', 'off');
            
            try
                % 根据文件类型加载数据
                if isMatFile
                    % 处理MAT文件
                    updateProgress(app, sprintf('📂 Loading MAT file: %s', currentFile));
                    loaded = load(filePath);
                    if ~isfield(loaded, 'foldData') || ~isfield(loaded, 'foldLabel')
                        updateProgress(app, sprintf('Skipping file %s: missing foldData or foldLabel', currentFile));
                        continue;
                    end
                    
                    % 预处理数据
                    updateProgress(app, sprintf('🧠 Processing MAT EEG data: %s', currentFile));
                    [foldData, foldLabel] = process_from_mat(...
                        loaded.foldData, loaded.foldLabel,...
                        app.FsEditField.Value, str2double(app.NotchFreqDropDown.Value),...
                        str2double(app.OrderDropDown.Value), app.NotchCheckBox.Value, app.BPCheckBox.Value,app.FpassHzEditField.Value,app.FstopHzEditField.Value);
                    
                elseif isZipFile
                    % 处理ZIP文件
                    updateProgress(app, sprintf('📦 Unzipping and processing: %s', currentFile));
                    
                    % 创建临时解压目录
                    temp_dir = tempname;
                    mkdir(temp_dir);
                    unzip(filePath, temp_dir);
                    
                    % 在解压目录中查找EEG数据和标签文件
                    eeg_txt_folder = '';
                    label_file = '';
                    files_in_zip = dir(temp_dir);
                    for j = 1:length(files_in_zip)
                        if files_in_zip(j).isdir && ~startsWith(files_in_zip(j).name, '.')
                            eeg_txt_folder = fullfile(temp_dir, files_in_zip(j).name);
                        elseif endsWith(files_in_zip(j).name, '.xlsx')
                            label_file = fullfile(temp_dir, files_in_zip(j).name);
                        end
                    end
                    
                    if isempty(eeg_txt_folder) || isempty(label_file)
                        updateProgress(app, sprintf('❌ Invalid ZIP structure in file: %s. Skipping.', currentFile));
                        rmdir(temp_dir, 's');
                        continue;
                    end
                    
                    % 处理ZIP文件中的数据
                    [foldData, foldLabel] = process_from_zip(...
                        eeg_txt_folder, label_file,...
                        app.FsEditField.Value, str2double(app.NotchFreqDropDown.Value),...
                        str2double(app.OrderDropDown.Value), str2double(app.CrossValidationFoldEditField.Value),...
                        app.NotchCheckBox.Value, app.BPCheckBox.Value,app.FpassHzEditField.Value,app.FstopHzEditField.Value);
                    
                    % 删除临时目录
                    rmdir(temp_dir, 's');
                end
                
                % 存储到app中供后续处理使用
                app.LoadedData.foldData = foldData;
                app.LoadedData.foldLabel = foldLabel;
                
                % ========== 特征提取设置 ==========
                fileProgressDlg.Value = 0;
                fileProgressDlg.Message = 'Initializing feature extraction...';
                
                % 检查特征提取方法
                if isempty(app.FeatureExtractionMethodListBox.Items)
                    if ~isempty(app.FeatureExtractionMethodDropDown_2.Value)
                        opts.type = app.FeatureExtractionMethodDropDown_2.Value;
                        opts.FeatureExtractMethod = {opts.type};
                        updateProgress(app, ['Feature Extraction Method : ', app.FeatureExtractionMethodDropDown_2.Value]);
                    end
                else
                    opts.type = buildProgressMessage(app);
                    opts.FeatureExtractMethod = app.FeatureExtractionMethodListBox.Items;
                    updateProgress(app, opts.type);
                end
                
                % 检查分类器选择
                if isempty(app.AllClassifiersListBox.Items)
                    if ~isempty(app.ClassifierDropDown.Value)
                        opts.ClassifierMethod = app.ClassifierDropDown.Value;
                        opts.Classifierchosed = {opts.ClassifierMethod};
                        updateProgress(app, ['Classifier: ', app.ClassifierDropDown.Value]);
                    end
                else
                    opts.ClassifierMethod = buildClassifiersProgressMessage(app);
                    opts.Classifierchosed = app.AllClassifiersListBox.Items;
                    updateProgress(app, opts.ClassifierMethod);
                end
                
                selectedWindowfunctionAbbr = app.WindowfunctionMapping(app.WindowFunctionDropDown.Value);
                selectedWindowfunctionFull = app.WindowFunctionDropDown.Value;
                opts.Windowfunction = selectedWindowfunctionAbbr;
                updateProgress(app, ['Window function: ', selectedWindowfunctionFull]);
                
                % 设置特征提取参数
                opts.nChannel = size(foldData{1}, 2);
                opts.nFrequency = size(foldData{1}, 3);
                opts.selFeatureNumPercent = 1;
                opts.feaSelIndex = [];
                
                % 时间范围处理
                items = app.TimeRangeListsListBox.Items;
                opts.TimeRngMat = [];
                for i = 1:length(items)
                    numItems = str2num(items{i});
                    opts.TimeRngMat = [opts.TimeRngMat; numItems];
                end
                
                updateProgress(app, 'The selected time band:');
                for i = 1:size(opts.TimeRngMat, 1)
                    rowStr = strcat(string(opts.TimeRngMat(i, 1)), '-', string(opts.TimeRngMat(i, 2)), 'seconds');
                    updateProgress(app, rowStr);
                end
                
                for i = 1:size(opts.TimeRngMat, 1)
                    opts.TimeRngMat(i, 1) = opts.TimeRngMat(i, 1) * opts.fs + 1;
                    opts.TimeRngMat(i, 2) = opts.TimeRngMat(i, 2) * opts.fs;
                end
                
                % 频率范围处理
                items2 = app.FrequencyRangeListHzListBox.Items;
                opts.FreqRngMat = [];
                for i = 1:length(items2)
                    numItems2 = str2num(items2{i});
                    opts.FreqRngMat = [opts.FreqRngMat; numItems2];
                end
                
                updateProgress(app, 'The selected frequency band:');
                for i = 1:size(opts.FreqRngMat, 1)
                    rowStr2 = strcat(string(opts.FreqRngMat(i, 1)), '-', string(opts.FreqRngMat(i, 2)), 'Hz');
                    updateProgress(app, rowStr2);
                end
                
                % ========== 特征提取 ==========
                fileProgressDlg.Value = 0.1;
                fileProgressDlg.Message = 'Starting feature extraction...';
                updateProgress(app, sprintf('Extracting features from %s...', currentFile));
                
                % 特征提取进度回调
                featureProgress = @(pct, msg) updateStageProgress(app, fileProgressDlg, 0, stageWeights(1), pct, ...
                    ['Feature Extraction: ' msg]);
                opts.progressCallback = featureProgress;
                
                [featureFoldData, featureFoldLabel] = extractFoldFeature(foldData, foldLabel, opts);
                
                % ========== 分类处理 ==========
                currentProgress = stageWeights(1);
                fileProgressDlg.Value = currentProgress;
                fileProgressDlg.Message = 'Starting classification...';
                
                nBlock = numel(featureFoldData);
                accuracy = zeros(nBlock, numel(opts.Classifierchosed));
                trainedClassifiers = cell(nBlock, numel(opts.Classifierchosed));
                
                % 分类器调用逻辑
                for iBlock = 1:nBlock
                    % 更新分类进度
                    blockProgress = (iBlock-1)/nBlock;
                    totalProgress = currentProgress + stageWeights(2)*blockProgress;
                    fileProgressDlg.Value = totalProgress;
                    fileProgressDlg.Message = sprintf('Classifying: Block %d/%d (%.1f%%)',...
                        iBlock, nBlock, blockProgress*100);
                    
                    % 准备训练和测试数据
                    testBlockFlag = false(1, numel(featureFoldData));
                    testBlockFlag(iBlock) = true;
                    testBlockData = featureFoldData(testBlockFlag);
                    testBlockLabel = featureFoldLabel(testBlockFlag);
                    trainBlockData = featureFoldData(~testBlockFlag);
                    trainBlockLabel = featureFoldLabel(~testBlockFlag);
                    
                    [DataTrain, DataTrainLabel, DataTest, DataTestLabel] = getAllDataTrain(...
                        trainBlockData, trainBlockLabel, testBlockData, testBlockLabel, opts);
                    
                    DataTrainLabel = categorical(DataTrainLabel);
                    DataTestLabel = categorical(DataTestLabel);
                    
                    % 确保 DataTestLabel 是列向量
                    if size(DataTestLabel, 1) == 1
                        DataTestLabel = DataTestLabel';
                    end
                    
                    % 遍历所有选择的分类器
                    for iClassifier = 1:numel(opts.Classifierchosed)
                        classifierName = opts.Classifierchosed{iClassifier};
                        
                        % 动态获取分类器函数句柄
                        classifierFunction = str2func(classifierName);
                        
                        % 调用分类器函数
                        trainedClassifier = classifierFunction(DataTrain, DataTrainLabel);
                        
                        % 保存训练好的分类器模型
                        trainedClassifiers{iBlock, iClassifier} = trainedClassifier;
                        
                        % 根据分类器类型进行预测
                        if strcmp(classifierName, 'ANN')
                            [result, ~] = predictANN(trainedClassifier, DataTest);
                            result = double(result);
                        elseif strcmp(classifierName, 'LSTM')
                            numFeatures = trainedClassifier.Layers(1).InputSize;
                            
                            % 转换为LSTM需要的cell array格式
                            lstmTestData = cell(size(DataTest,1), 1);
                            for i = 1:size(DataTest,1)
                                lstmTestData{i} = reshape(DataTest(i,:), [numFeatures, 1]);
                            end
                            
                            % 进行预测并处理输出
                            pred = predict(trainedClassifier, lstmTestData);
                            
                            if size(pred, 2) > 1
                                [~, result] = max(pred, [], 2);
                            end
                            result = double(result(:));
                        else 
                            result = double(predict(trainedClassifier, DataTest));
                        end
                        
                        % 计算准确率
                        accuracy(iBlock, iClassifier) = sum(result == double(DataTestLabel)) / numel(DataTestLabel);
                        accPercentage = accuracy(iBlock, iClassifier) * 100;
                        
                        % 构造消息字符串
                        message = sprintf('File %s - Block %d/%d, Classifier: %s, Accuracy: %.2f%%\n', ...
                            currentFile, iBlock, nBlock, classifierName, accPercentage);
                        
                        % 更新进度信息
                        updateProgress(app, message);
                    end
                end
                
                % 计算每个分类器的平均准确率
                avgAccuracy = mean(accuracy, 1) * 100;
                
                % 存储结果
                currentResult.filename = currentFile;
                currentResult.accuracy = accuracy;
                currentResult.avgAccuracy = avgAccuracy;
                currentResult.classifiers = opts.Classifierchosed;
                allResults(end+1) = currentResult;
                
                % 显示当前文件结果
                for iClassifier = 1:numel(opts.Classifierchosed)
                    updateProgress(app, sprintf('File %s - Avg Accuracy for %s: %.2f%%', ...
                        currentFile, opts.Classifierchosed{iClassifier}, avgAccuracy(iClassifier)));
                end
                
                % 保存当前文件的处理结果
                [~, name, ~] = fileparts(currentFile);
                folderName = [name, '_ProcessingResults'];
                
                if strcmp(fileType, 'folder')
                    folderPath = fullfile(app.SelectedFile, folderName);
                else
                    folderPath = fullfile(fileparts(app.SelectedFile), folderName);
                end
                
                if ~exist(folderPath, 'dir')
                    mkdir(folderPath);
                end
                
                % 保存处理结果 - 使用 opts 而不是 app.opts
              
                opts.filename = currentFile;
                opts.selectedOption = selectedOption;
                % 保存处理结果前压缩分类器模型
                opts.trainedClassifiers = cellfun(@(x) compact(x), trainedClassifiers, 'UniformOutput', false);
                 if isfield(opts, 'progressCallback')
                    opts = rmfield(opts, 'progressCallback');
                end
                % 保存处理结果
                save(fullfile(folderPath, 'processing_results1.mat'), 'opts');
                updateProgress(app, ['Results for ' currentFile ' saved to: ' folderPath]);


                % 完成当前文件处理
                fileProgressDlg.Value = 1;
                fileProgressDlg.Message = 'File processing completed!';
                pause(0.5);
                close(fileProgressDlg);
                
            catch ME
                close(fileProgressDlg);
                updateProgress(app, sprintf('Error processing file %s: %s', currentFile, ME.message));
                continue; % 继续处理下一个文件
            end
        end
        
       % 保存汇总结果
if ~isempty(allResults)
    % 创建汇总Excel文件
    if strcmp(fileType, 'folder')
        excelFileName = fullfile(app.SelectedFile, 'classification_results_summary1.xlsx');
    else
        excelFileName = fullfile(fileparts(app.SelectedFile), 'classification_results_summary1.xlsx');
    end
    
    % 准备数据 - 汇总表
    numClassifiers = numel(allResults(1).classifiers);
    headers = ['Filename', allResults(1).classifiers, 'Overall Average'];
    
    % 创建数据单元格数组并添加百分号
    data = cell(numel(allResults)+1, numClassifiers+2);
    data(1,:) = headers;
    
    for i = 1:numel(allResults)
        data{i+1,1} = allResults(i).filename;
        for j = 1:numClassifiers
            % 添加百分号显示
            data{i+1,j+1} = [num2str(allResults(i).avgAccuracy(j)) '%'];
        end
        % 总体平均值也添加百分号
        data{i+1,numClassifiers+2} = [num2str(mean(allResults(i).avgAccuracy)) '%'];
    end
    
    % 写入Excel
    writecell(data, excelFileName);
    updateProgress(app, ['Summary results saved to: ' excelFileName]);
    
    % 保存详细准确率(每个block的结果)
    detailFileName = strrep(excelFileName, '_summary1.xlsx', '_details1.xlsx');
    for i = 1:numel(allResults)
        sheetName = strrep(allResults(i).filename, '.mat', '');
        sheetName = strrep(sheetName, '.zip', '');
        if length(sheetName) > 31
            sheetName = sheetName(1:31);
        end
        
        % 准备详细数据 - 将准确率转换为百分数形式
        accuracyData = allResults(i).accuracy*100; % 转换为百分比
        detailData = num2cell(accuracyData);
        
        % 为每个单元格添加百分号
        for row = 1:size(detailData,1)
            for col = 1:size(detailData,2)
                detailData{row,col} = [num2str(detailData{row,col}) '%'];
            end
        end
        
        detailHeaders = allResults(i).classifiers;
        
        % 确保 blockNumbers 和 detailData 行数一致
        nBlocks = size(allResults(i).accuracy, 1);
        blockNumbers = (1:nBlocks)';
        
        % 只有当 blockNumbers 和 detailData 行数一致时才进行合并
        if length(blockNumbers) == size(detailData, 1)
            detailTable = [num2cell(blockNumbers), detailData];
            detailHeaders = ['Block', detailHeaders];
            
            % 写入sheet
            writetable(cell2table(detailTable, 'VariableNames', detailHeaders), ...
                      detailFileName, 'Sheet', sheetName, 'WriteVariableNames', true);
        else
            % 如果不一致，只写入准确率数据
            updateProgress(app, sprintf('Warning: Block numbers mismatch for file %s. Saving accuracy data only.', allResults(i).filename));
            writetable(cell2table(detailData, 'VariableNames', detailHeaders), ...
                      detailFileName, 'Sheet', sheetName, 'WriteVariableNames', true);
        end
    end
    updateProgress(app, ['Detailed results saved to: ' detailFileName]);
end
        
        % 所有文件处理完成
        mainProgressDlg.Value = 1;
        mainProgressDlg.Message = 'All files processed successfully!';
        pause(0.5);
        close(mainProgressDlg);
        
    catch ME
        if ishandle(mainProgressDlg)
            close(mainProgressDlg);
        end
        updateProgress(app, ['Final Error: ', ME.message]);
        rethrow(ME);
    end

case 'Select Certain Features'
    % 初始化主进度条
    mainProgressDlg = uiprogressdlg(app.UIFigure, 'Title', 'Multi-File Processing Pipeline',...
                                   'Message', 'Starting processing...',...
                                   'Indeterminate', 'off');
    
    try
        % 判断输入类型（单个文件或文件夹）
        if isfolder(app.SelectedFile)
            % 处理文件夹情况
            matFiles = dir(fullfile(app.SelectedFile, '*.mat'));
            zipFiles = dir(fullfile(app.SelectedFile, '*.zip'));
            allFiles = [matFiles; zipFiles];
            numFiles = length(allFiles);
            
            if numFiles == 0
                error('No MAT or ZIP files found in the selected folder.');
            end
            
            fileType = 'folder';
        else
            % 处理单个文件情况
            [~,~,ext] = fileparts(app.SelectedFile);
            if strcmpi(ext, '.mat')
                allFiles.name = app.SelectedFile;
                fileType = 'single_mat';
            elseif strcmpi(ext, '.zip')
                allFiles.name = app.SelectedFile;
                fileType = 'single_zip';
            else
                error('Unsupported file type. Only .mat and .zip files are supported.');
            end
            numFiles = 1;
        end
        
        % 初始化结果存储结构
        allResults = struct('filename', {}, 'accuracy', {}, 'avgAccuracy', {}, 'classifiers', {}, ...
                            'featureMask', {}, 'featureScores', {}, 'selectedOption', {}, ...
                            'trainedClassifiers', {});
        
        % 定义各阶段权重（特征提取40%，特征选择30%，分类30%）
        stageWeights = [0.4, 0.3, 0.3];
        
        % 循环处理每个文件
        for fileIdx = 1:numFiles
            if strcmp(fileType, 'folder')
                currentFile = allFiles(fileIdx).name;
                filePath = fullfile(app.SelectedFile, currentFile);
                [~,~,ext] = fileparts(currentFile);
                isMatFile = strcmpi(ext, '.mat');
                isZipFile = strcmpi(ext, '.zip');
            else
                filePath = app.SelectedFile;
                [~,currentFile,ext] = fileparts(filePath);
                currentFile = [currentFile ext];
                isMatFile = strcmpi(ext, '.mat');
                isZipFile = strcmpi(ext, '.zip');
            end
            
            % 更新主进度条
            mainProgressDlg.Value = (fileIdx-1)/numFiles;
            mainProgressDlg.Message = sprintf('Processing %d/%d: %s',...
                fileIdx, numFiles, currentFile);
            drawnow;
            
            % 为当前文件创建处理进度条
            fileProgressDlg = uiprogressdlg(app.UIFigure, 'Title', sprintf('Processing %s', currentFile),...
                                          'Message', 'Loading file...',...
                                          'Indeterminate', 'off');
           
                try
                % 根据文件类型加载数据
                if isMatFile
                    % 处理MAT文件
                    updateProgress(app, sprintf('📂 Loading MAT file: %s', currentFile));
                    loaded = load(filePath);
                    if ~isfield(loaded, 'foldData') || ~isfield(loaded, 'foldLabel')
                        updateProgress(app, sprintf('Skipping file %s: missing foldData or foldLabel', currentFile));
                        continue;
                    end
                    
                    % 预处理数据
                    updateProgress(app, sprintf('🧠 Processing MAT EEG data: %s', currentFile));
                    [foldData, foldLabel] = process_from_mat(...
                        loaded.foldData, loaded.foldLabel,...
                        app.FsEditField.Value, str2double(app.NotchFreqDropDown.Value),...
                        str2double(app.OrderDropDown.Value), app.NotchCheckBox.Value, app.BPCheckBox.Value,app.FpassHzEditField.Value,app.FstopHzEditField.Value);
                    
                    loadedData.foldData = foldData;
                    loadedData.foldLabel =foldLabel;
                elseif isZipFile
                    % 处理ZIP文件
                    updateProgress(app, sprintf('📦 Unzipping and processing: %s', currentFile));
                    
                    % 创建临时解压目录
                    temp_dir = tempname;
                    mkdir(temp_dir);
                    unzip(filePath, temp_dir);
                    
                    % 在解压目录中查找EEG数据和标签文件
                    eeg_txt_folder = '';
                    label_file = '';
                    files_in_zip = dir(temp_dir);
                    for j = 1:length(files_in_zip)
                        if files_in_zip(j).isdir && ~startsWith(files_in_zip(j).name, '.')
                            eeg_txt_folder = fullfile(temp_dir, files_in_zip(j).name);
                        elseif endsWith(files_in_zip(j).name, '.xlsx')
                            label_file = fullfile(temp_dir, files_in_zip(j).name);
                        end
                    end
                    
                    if isempty(eeg_txt_folder) || isempty(label_file)
                        updateProgress(app, sprintf('❌ Invalid ZIP structure in file: %s. Skipping.', currentFile));
                        rmdir(temp_dir, 's');
                        continue;
                    end
                    
                    % 处理ZIP文件中的数据
                    [foldData, foldLabel] = process_from_zip(...
                                                eeg_txt_folder, label_file,...
                        app.FsEditField.Value, str2double(app.NotchFreqDropDown.Value),...
                        str2double(app.OrderDropDown.Value), str2double(app.CrossValidationFoldEditField.Value),...
                        app.NotchCheckBox.Value, app.BPCheckBox.Value,str2double(app.FpassHzEditField.Value),str2double(app.FstopHzEditField.Value));
                    
                    % 删除临时目录
                    rmdir(temp_dir, 's');
                    
                    % 将数据存储到结构体中
                    loadedData.foldData = foldData;
                    loadedData.foldLabel = foldLabel;
                end
                app.opts.nFrequency = size(loadedData.foldData{1}, 3);
                app.opts.nChannel = size(loadedData.foldData{1}, 2);
                numBlocks = size(loadedData.foldData, 2);

                % 初始化特征提取和分类选项
                app.opts.progressCallback = @updateFeatureProgress;
                
                % 检查特征提取方法
                if isempty(app.FeatureExtractionMethodListBox.Items)
                    if ~isempty(app.FeatureExtractionMethodDropDown_2.Value)
                        app.opts.type = app.FeatureExtractionMethodDropDown_2.Value;
                        app.opts.FeatureExtractMethod = {app.opts.type};
                        updateProgress(app, ['Feature Extraction Method : ', app.FeatureExtractionMethodDropDown_2.Value]);
                    else
                        app.opts.type = ''; % 如果没有选择任何方法，则设置为空字符串
                    end
                else
                    app.opts.type = buildProgressMessage(app);
                    app.opts.FeatureExtractMethod = app.FeatureExtractionMethodListBox.Items;
                    updateProgress(app, app.opts.type);
                end
                
                % 检查分类器选择
                if isempty(app.AllClassifiersListBox.Items)
                    if ~isempty(app.ClassifierDropDown.Value)
                        app.opts.ClassifierMethod = app.ClassifierDropDown.Value;
                        app.opts.Classifierchosed = {app.opts.ClassifierMethod};
                        updateProgress(app, ['Classifier: ', app.ClassifierDropDown.Value]);
                    else
                        app.opts.ClassifierMethod = ''; % 如果没有选择任何方法，则设置为空字符串
                    end
                else
                    app.opts.ClassifierMethod = buildClassifiersProgressMessage(app);
                    app.opts.Classifierchosed = app.AllClassifiersListBox.Items;
                    updateProgress(app, app.opts.ClassifierMethod);
                end
                
                % 检查特征选择方法
                if ~isempty(app.FeatureSelectionMethodDropDown.Value)
                    app.opts.FeatureSelectionName = app.FeatureSelectionMethodDropDown.Value;
                    app.opts.FeatureSelectionNamestring = {app.opts.FeatureSelectionName};
                else
                    app.opts.FeatureSelectionName = ''; % 如果没有选择任何方法，则设置为空字符串
                end
                updateProgress(app, ['Feature Selection Method: ',  app.FeatureSelectionMethodDropDown.Value]);
                
   
                selectedWindowfunctionAbbr = app.WindowfunctionMapping(app.WindowFunctionDropDown.Value);
                selectedWindowfunctionFull = app.WindowFunctionDropDown.Value;
                app.opts.Windowfunction = selectedWindowfunctionAbbr;
                updateProgress(app, ['Window Function: ',  selectedWindowfunctionFull]);
                
  % 时间范围处理
                items = app.TimeRangeListsListBox.Items;
                app.opts.TimeRngMat = [];
                for i = 1:length(items)
                    numItems = str2num(items{i});
                    app.opts.TimeRngMat = [app.opts.TimeRngMat; numItems];
                end
                
                updateProgress(app, 'The selected time band:');
                for i = 1:size(app.opts.TimeRngMat, 1)
                    rowStr = strcat(string(app.opts.TimeRngMat(i, 1)), '-', string(app.opts.TimeRngMat(i, 2)), 'seconds');
                    updateProgress(app, rowStr);
                end
                
                for i = 1:size(app.opts.TimeRngMat, 1)
                    app.opts.TimeRngMat(i, 1) = app.opts.TimeRngMat(i, 1) * app.opts.fs + 1;
                    app.opts.TimeRngMat(i, 2) = app.opts.TimeRngMat(i, 2) * app.opts.fs;
                end
                
                % 频率范围处理
                items2 = app.FrequencyRangeListHzListBox.Items;
                app.opts.FreqRngMat = [];
                for i = 1:length(items2)
                    numItems2 = str2num(items2{i});
                    app.opts.FreqRngMat = [app.opts.FreqRngMat; numItems2];
                end
                
                updateProgress(app, 'The selected frequency band:');
                for i = 1:size(app.opts.FreqRngMat, 1)
                    rowStr2 = strcat(string(app.opts.FreqRngMat(i, 1)), '-', string(app.opts.FreqRngMat(i, 2)), 'Hz');
                    updateProgress(app, rowStr2);
                end
                

                % 特征提取和分类的主逻辑
                % 初始化进度条
                fileProgressDlg.Value = 0.2;
                fileProgressDlg.Message = 'Initializing feature extraction...';
                
                % 特征提取阶段
                updateProgress(app, 'Extracting features...');
                [app.opts.featureFoldData, app.opts.featureFoldLabel] = extractFoldFeature(loadedData.foldData, loadedData.foldLabel, app.opts);
                
                % 特征选择阶段
                fileProgressDlg.Value = stageWeights(1);
                fileProgressDlg.Message = 'Starting feature selection...';
                
                % 获取用户输入的特征选择百分比
                userInput = app.FeatureSelectionRateEditField.Value; % 获取用户输入的值
                if isnumeric(userInput)
                    userInput = string(userInput);
                end
                feaSelPercent = (str2double(userInput))*0.01;
                if isnan(feaSelPercent) || feaSelPercent < 0 || feaSelPercent > 100
                    error('Feature selection percentage must be between 0% and 100%.');
                end
                app.opts.selFeatureNumPercent = feaSelPercent;
                
                % 执行特征选择
                [ResData, ResultsData] = Main_Func_FS(app.opts);
                featureScores = ResultsData.OutputData.meanFeaSelScore;
                selFeatureIdx = ResultsData.OutputData.selFeatureIdxVector;
                featureMask = false(1, numel(featureScores));
                featureMask(selFeatureIdx) = true;
                app.ResData = ResData;
                updateMapButtonState(app);
                drawnow;
                
                % 分类阶段
                fileProgressDlg.Value = stageWeights(1) + stageWeights(2);
                fileProgressDlg.Message = 'Starting classification...';
                
                % 获取分类结果
                Blockclassifier = ResultsData.blockclassifier;
                meanAccuracies = mean(Blockclassifier, 1) * 100; % 按列计算平均值，得到每个分类器的平均准确率
                
                % 存储结果
                currentResult.filename = currentFile;
                currentResult.accuracy = Blockclassifier;
                currentResult.avgAccuracy = meanAccuracies;
                currentResult.classifiers = app.opts.Classifierchosed;
                currentResult.featureMask = featureMask;
                currentResult.featureScores = featureScores;
                currentResult.selectedOption = 'Select Certain Features';
                currentResult.trainedClassifiers = ResultsData.trainedClassifiers;
                allResults(end+1) = currentResult;
                
                % 显示当前文件结果
                for iClassifier = 1:numel(app.opts.Classifierchosed)
                    updateProgress(app, sprintf('File %s - Avg Accuracy for %s: %.2f%%', ...
                        currentFile, app.opts.Classifierchosed{iClassifier}, meanAccuracies(iClassifier)));
                end
                
                % 保存当前文件的处理结果
                [~, name, ~] = fileparts(currentFile);
                folderName = [name, '_ProcessingResults'];
                
                if strcmp(fileType, 'folder')
                    folderPath = fullfile(app.SelectedFile, folderName);
                else
                    folderPath = fullfile(fileparts(app.SelectedFile), folderName);
                end
                
                if ~exist(folderPath, 'dir')
                    mkdir(folderPath);
                end


                % 在保存结果部分修改为：
                
                % 复制基本参数

                savedOpts.nFrequency = app.opts.nFrequency;
                savedOpts.nChannel = app.opts.nChannel;
                savedOpts.type = app.opts.type;
                savedOpts.fs = app.opts.fs;
                savedOpts.FeatureExtractMethod = app.opts.FeatureExtractMethod;
                savedOpts.ClassifierMethod = app.opts.ClassifierMethod;
                savedOpts.Classifierchosed = app.opts.Classifierchosed;
                savedOpts.FeatureSelectionName = app.opts.FeatureSelectionName;
                savedOpts.Windowfunction = app.opts.Windowfunction;
                savedOpts.TimeRngMat = app.opts.TimeRngMat;
                savedOpts.FreqRngMat = app.opts.FreqRngMat;
                savedOpts.selFeatureNumPercent = app.opts.selFeatureNumPercent;
                
                % 添加结果数据
                savedOpts.featureMask = featureMask;
                savedOpts.featureScores = featureScores;
                savedOpts.selectedOption = 'Select Certain Features';
                                             
                % 同时更新app.opts，但不包含不需要的字段
                opts = savedOpts;
                if isfield(opts, 'progressCallback')
                    opts = rmfield(opts, 'progressCallback');
                end
                if isfield(opts, 'featureFoldData')
                    opts = rmfield(opts, 'featureFoldData');
                end
                if isfield(opts, 'featureFoldLabel')
                    opts = rmfield(opts, 'featureFoldLabel');
                end

               % 压缩所有已训练的分类器
                opts.trainedClassifiers = cellfun(@compact, ResultsData.trainedClassifiers, ...
                                  'UniformOutput', false);
                %opts.loadedData =loadedData;
               
                opts.ResData = ResData;

                opts.filename = currentFile;
                save(fullfile(folderPath, 'processing_results2.mat'), 'opts');
                
                % 更新进度条
                fileProgressDlg.Value = 1;
                fileProgressDlg.Message = 'File processing completed!';
                pause(0.5);
                close(fileProgressDlg);
                
            catch ME
                close(fileProgressDlg);
                updateProgress(app, sprintf('Error processing file %s: %s', currentFile, ME.message));
                continue; % 继续处理下一个文件
            end
        end
        
% 保存汇总结果到 Excel 文件
if ~isempty(allResults)
    % 创建汇总 Excel 文件
    if strcmp(fileType, 'folder')
        summaryExcelFileName = fullfile(app.SelectedFile, 'classification_results_summary2.xlsx');
        detailedExcelFileName = fullfile(app.SelectedFile, 'classification_results_details2.xlsx');
    else
        summaryExcelFileName = fullfile(fileparts(app.SelectedFile), 'classification_results_summary2.xlsx');
        detailedExcelFileName = fullfile(fileparts(app.SelectedFile), 'classification_results_details2.xlsx');
    end
    
    % 准备数据 - 汇总表
    numClassifiers = numel(allResults(1).classifiers);
    summaryHeaders = ['Filename', allResults(1).classifiers, 'Overall Average'];
    
    % 创建数据单元格数组并添加百分号
    summaryData = cell(numel(allResults)+1, numClassifiers+2);
    summaryData(1,:) = summaryHeaders;
    
    for i = 1:numel(allResults)
        summaryData{i+1,1} = allResults(i).filename;
        for j = 1:numClassifiers
            % 添加百分号显示
            summaryData{i+1,j+1} = [num2str(allResults(i).avgAccuracy(j)) '%'];
        end
        % 总体平均值也添加百分号
        summaryData{i+1,numClassifiers+2} = [num2str(mean(allResults(i).avgAccuracy)) '%'];
    end
    
    % 写入汇总表到 Excel
    writecell(summaryData, summaryExcelFileName);
    updateProgress(app, ['Summary results saved to: ' summaryExcelFileName]);
    
    % 准备数据 - 详细表
    for i = 1:numel(allResults)
        % 获取当前文件的详细数据
        currentFile = allResults(i).filename;
        accuracyData = allResults(i).accuracy * 100; % 转换为百分比
        detailedData = num2cell(accuracyData);
        
        % 为每个单元格添加百分号
        for row = 1:size(detailedData, 1)
            for col = 1:size(detailedData, 2)
                detailedData{row, col} = [num2str(detailedData{row, col}) '%'];
            end
        end
        
        % 添加块编号
        nBlocks = size(accuracyData, 1);
        blockNumbers = (1:nBlocks)';
        detailedData = [num2cell(blockNumbers), detailedData];
        
        % 创建表头
        detailedHeaders = ['Block', allResults(i).classifiers];
        
        % 写入详细表到 Excel
        sheetName = strrep(currentFile, '.mat', '');
        sheetName = strrep(sheetName, '.zip', '');
        if length(sheetName) > 31
            sheetName = sheetName(1:31); % Excel sheet 名称的最大长度为 31
        end
        writetable(cell2table(detailedData, 'VariableNames', detailedHeaders), ...
                   detailedExcelFileName, 'Sheet', sheetName, 'WriteVariableNames', true);
    end
    updateProgress(app, ['Detailed results saved to: ' detailedExcelFileName]);
end
        % 所有文件处理完成
        mainProgressDlg.Value = 1;
        mainProgressDlg.Message = 'All files processed successfully!';
        pause(0.5);
        close(mainProgressDlg);
        
    catch ME
        if ishandle(mainProgressDlg)
            close(mainProgressDlg);
        end
        updateProgress(app, ['Final Error: ', ME.message]);
        rethrow(ME);
    end


case 'Traverse All Feature Ratios'
    % 初始化主进度条
    mainProgressDlg = uiprogressdlg(app.UIFigure, 'Title', 'Multi-File Processing Pipeline',...
                               'Message', 'Starting processing...',...
                               'Indeterminate', 'off');
    
    try
        % 判断输入类型（单个文件或文件夹）
        if isfolder(app.SelectedFile)
            % 处理文件夹情况
            matFiles = dir(fullfile(app.SelectedFile, '*.mat'));
            zipFiles = dir(fullfile(app.SelectedFile, '*.zip'));
            allFiles = [matFiles; zipFiles];
            numFiles = length(allFiles);
            
            if numFiles == 0
                error('No MAT or ZIP files found in the selected folder.');
            end
            
            fileType = 'folder';
        else
            % 处理单个文件情况
            [~,~,ext] = fileparts(app.SelectedFile);
            if strcmpi(ext, '.mat')
                allFiles.name = app.SelectedFile;
                fileType = 'single_mat';
            elseif strcmpi(ext, '.zip')
                allFiles.name = app.SelectedFile;
                fileType = 'single_zip';
            else
                error('Unsupported file type. Only .mat and .zip files are supported.');
            end
            numFiles = 1;
        end
        
        % 初始化结果存储结构
        allResults = struct('filename', {}, 'Blockclassifier', {}, 'bestAccuracy', {}, ...
                           'bestFeatureRatio', {}, 'classifiers', {}, 'featureRatios', {});
        
        % 定义各阶段权重（初始化设置20%，特征提取20%，特征选择和分类60%）
        stageWeights = [0.2, 0.2, 0.6];
        
        % 定义特征选择比例（1% - 100%）
        feaSelNumPercent = 0.01:0.01:1;
        
        % 循环处理每个文件
        for fileIdx = 1:numFiles
            if strcmp(fileType, 'folder')
                currentFile = allFiles(fileIdx).name;
                filePath = fullfile(app.SelectedFile, currentFile);
                [~,~,ext] = fileparts(currentFile);
                isMatFile = strcmpi(ext, '.mat');
                isZipFile = strcmpi(ext, '.zip');
            else
                filePath = app.SelectedFile;
                [~,currentFile,ext] = fileparts(filePath);
                currentFile = [currentFile ext];
                isMatFile = strcmpi(ext, '.mat');
                isZipFile = strcmpi(ext, '.zip');
            end
            
            % 更新主进度条
            mainProgressDlg.Value = (fileIdx-1)/numFiles;
            mainProgressDlg.Message = sprintf('Processing %d/%d: %s',...
                fileIdx, numFiles, currentFile);
            drawnow;
            
            % 为当前文件创建处理进度条
            fileProgressDlg = uiprogressdlg(app.UIFigure, 'Title', sprintf('Processing %s', currentFile),...
                                          'Message', 'Loading file...',...
                                          'Indeterminate', 'off');
            
            try
                % 根据文件类型加载数据
                if isMatFile
                    % 处理MAT文件
                    updateProgress(app, sprintf('📂 Loading MAT file: %s', currentFile));
                    loaded = load(filePath);
                    if ~isfield(loaded, 'foldData') || ~isfield(loaded, 'foldLabel')
                        updateProgress(app, sprintf('Skipping file %s: missing foldData or foldLabel', currentFile));
                        continue;
                    end
                    
                    % 预处理数据
                    updateProgress(app, sprintf('🧠 Processing MAT EEG data: %s', currentFile));
                    [foldData, foldLabel] = process_from_mat(...
                        loaded.foldData, loaded.foldLabel,...
                        app.FsEditField.Value, str2double(app.NotchFreqDropDown.Value),...
                        str2double(app.OrderDropDown.Value), app.NotchCheckBox.Value, app.BPCheckBox.Value,app.FpassHzEditField.Value,app.FstopHzEditField.Value);
                    
               
                    % 预处理数据
                    updateProgress(app, sprintf('🧠 Processing MAT EEG data: %s', currentFile));
                    [foldData, foldLabel] = process_from_mat(...
                        loaded.foldData, loaded.foldLabel,...
                        app.FsEditField.Value, str2double(app.NotchFreqDropDown.Value),...
                        str2double(app.OrderDropDown.Value), app.NotchCheckBox.Value, app.BPCheckBox.Value);
                       loadedData.foldData = foldData;
                    loadedData.foldLabel = foldLabel;
                elseif isZipFile
                    % 处理ZIP文件
                    updateProgress(app, sprintf('📦 Unzipping and processing: %s', currentFile));
                    
                    % 创建临时解压目录
                    temp_dir = tempname;
                    mkdir(temp_dir);
                    unzip(filePath, temp_dir);
                    
                    % 在解压目录中查找EEG数据和标签文件
                    eeg_txt_folder = '';
                    label_file = '';
                    files_in_zip = dir(temp_dir);
                    for j = 1:length(files_in_zip)
                        if files_in_zip(j).isdir && ~startsWith(files_in_zip(j).name, '.')
                            eeg_txt_folder = fullfile(temp_dir, files_in_zip(j).name);
                        elseif endsWith(files_in_zip(j).name, '.xlsx')
                            label_file = fullfile(temp_dir, files_in_zip(j).name);
                        end
                    end
                    
                    if isempty(eeg_txt_folder) || isempty(label_file)
                        updateProgress(app, sprintf('❌ Invalid ZIP structure in file: %s. Skipping.', currentFile));
                        rmdir(temp_dir, 's');
                        continue;
                    end
                    
                    % 处理ZIP文件中的数据
                    [foldData, foldLabel] = process_from_zip(...
                        eeg_txt_folder, label_file,...
                        app.FsEditField.Value, str2double(app.NotchFreqDropDown.Value),...
                        str2double(app.OrderDropDown.Value), str2double(app.CrossValidationFoldEditField.Value),...
                        app.NotchCheckBox.Value, app.BPCheckBox.Value,app.FpassHzEditField.Value,app.FstopHzEditField.Value);
                    
                    % 删除临时目录
                    rmdir(temp_dir, 's');
                end
                
                % 确保foldData和foldLabel是正确格式的cell数组
                if ~iscell(foldData)
                    foldData = {foldData};
                end
                if ~iscell(foldLabel)
                    foldLabel = {foldLabel};
                end
                
                % 存储到app中供后续处理使用
                app.LoadedData.foldData = foldData;
                app.LoadedData.foldLabel = foldLabel;

                loadedData.foldData = loaded.foldData;
                loadedData.foldLabel = loaded.foldLabel;
                
                % ========== 特征提取设置 ==========
                fileProgressDlg.Value = 0;
                fileProgressDlg.Message = 'Initializing feature extraction...';
                
                % 检查特征提取方法
                if isempty(app.FeatureExtractionMethodListBox.Items)
                    if ~isempty(app.FeatureExtractionMethodDropDown_2.Value)
                        app.opts.type = app.FeatureExtractionMethodDropDown_2.Value;
                        app.opts.FeatureExtractMethod = {app.opts.type};
                        updateProgress(app, ['Feature Extraction Method : ', app.FeatureExtractionMethodDropDown_2.Value]);
                    else
                        error('No feature extraction method selected');
                    end
                else
                    app.opts.type = buildProgressMessage(app);
                    app.opts.FeatureExtractMethod = app.FeatureExtractionMethodListBox.Items;
                    updateProgress(app, app.opts.type);
                end
                
                % 检查分类器选择
                if isempty(app.AllClassifiersListBox.Items)
                    if ~isempty(app.ClassifierDropDown.Value)
                        app.opts.ClassifierMethod = app.ClassifierDropDown.Value;
                        app.opts.Classifierchosed = {app.opts.ClassifierMethod};
                        updateProgress(app, ['Classifier: ', app.ClassifierDropDown.Value]);
                    else
                        error('No classifier selected');
                    end
                else
                    app.opts.ClassifierMethod = buildClassifiersProgressMessage(app);
                    app.opts.Classifierchosed = app.AllClassifiersListBox.Items;
                    updateProgress(app, app.opts.ClassifierMethod);
                end
                
                % 检查特征选择方法
                if ~isempty(app.FeatureSelectionMethodDropDown.Value)
                    app.opts.FeatureSelectionName = app.FeatureSelectionMethodDropDown.Value;
                    app.opts.FeatureSelectionNamestring = {app.FeatureSelectionMethodDropDown.Value};
                    updateProgress(app, ['Feature Selection Method: ', app.FeatureSelectionMethodDropDown.Value]);
                else
                    error('No feature selection method selected');
                end
                
                
                selectedWindowfunctionAbbr = app.WindowfunctionMapping(app.WindowFunctionDropDown.Value);
                selectedWindowfunctionFull = app.WindowFunctionDropDown.Value;
                app.opts.Windowfunction = selectedWindowfunctionAbbr;
                updateProgress(app, ['Window function: ', selectedWindowfunctionFull]);
                
                % 设置特征提取参数
                app.opts.nChannel = size(foldData{1}, 2);
                app.opts.nFrequency = size(foldData{1}, 3);
                app.opts.fs = app.FsEditField.Value; % 使用用户设置的采样率
                app.opts.feaSelIndex = [];
                
                % 时间范围处理
                if ~isempty(app.TimeRangeListsListBox.Items)
                    items = app.TimeRangeListsListBox.Items;
                    app.opts.TimeRngMat = [];
                    for i = 1:length(items)
                        numItems = str2num(items{i});
                        if ~isempty(numItems) && numel(numItems) == 2
                            app.opts.TimeRngMat = [app.opts.TimeRngMat; numItems];
                        end
                    end
                    
                    if ~isempty(app.opts.TimeRngMat)
                        updateProgress(app, 'The selected time band:');
                        for i = 1:size(app.opts.TimeRngMat, 1)
                            rowStr = strcat(string(app.opts.TimeRngMat(i, 1)), '-', string(app.opts.TimeRngMat(i, 2)), 'seconds');
                            updateProgress(app, rowStr);
                        end
                        
                        for i = 1:size(app.opts.TimeRngMat, 1)
                            app.opts.TimeRngMat(i, 1) = app.opts.TimeRngMat(i, 1) * app.opts.fs + 1;
                            app.opts.TimeRngMat(i, 2) = app.opts.TimeRngMat(i, 2) * app.opts.fs;
                        end
                    else
                        app.opts.TimeRngMat = [1 size(foldData{1},1)]; % 默认使用全部时间范围
                    end
                else
                    app.opts.TimeRngMat = [1 size(foldData{1},1)]; % 默认使用全部时间范围
                end
                
                % 频率范围处理
                if ~isempty(app.FrequencyRangeListHzListBox.Items)
                    items2 = app.FrequencyRangeListHzListBox.Items;
                    app.opts.FreqRngMat = [];
                    for i = 1:length(items2)
                        numItems2 = str2num(items2{i});
                        if ~isempty(numItems2) && numel(numItems2) == 2
                            app.opts.FreqRngMat = [app.opts.FreqRngMat; numItems2];
                        end
                    end
                    
                    if ~isempty(app.opts.FreqRngMat)
                        updateProgress(app, 'The selected frequency band:');
                        for i = 1:size(app.opts.FreqRngMat, 1)
                            rowStr2 = strcat(string(app.opts.FreqRngMat(i, 1)), '-', string(app.opts.FreqRngMat(i, 2)), 'Hz');
                            updateProgress(app, rowStr2);
                        end
                    else
                        app.opts.FreqRngMat = [0 app.opts.fs/2]; % 默认使用全部频率范围
                    end
                else
                    app.opts.FreqRngMat = [0 app.opts.fs/2]; % 默认使用全部频率范围
                end
                
                % ========== 特征提取 ==========
                fileProgressDlg.Value = 0.1;
                fileProgressDlg.Message = 'Starting feature extraction...';
                updateProgress(app, sprintf('Extracting features from %s...', currentFile));
                
                % 特征提取进度回调
                featureProgress = @(pct, msg) updateStageProgress(app, fileProgressDlg, 0, stageWeights(1), pct, ...
                    ['Feature Extraction: ' msg]);
                app.opts.progressCallback = featureProgress;
                
                % 执行特征提取
                try
                    [featureFoldData, featureFoldLabel] = extractFoldFeature(foldData, foldLabel, app.opts);
                    
                    % 确保特征提取后的数据格式正确
                    if ~iscell(featureFoldData) || ~iscell(featureFoldLabel)
                        error('Feature extraction did not return cell arrays');
                    end
                    
                    % 将提取的特征存储到opts中
                    app.opts.featureFoldData = featureFoldData;
                    app.opts.featureFoldLabel = featureFoldLabel;
                    
                catch ME
                    error('Feature extraction failed: %s', ME.message);
                end
                
                % ========== 特征选择和分类 ==========
                currentProgress = stageWeights(1);
                fileProgressDlg.Value = currentProgress;
                fileProgressDlg.Message = 'Starting feature selection and classification...';
                
                numBlocks = numel(featureFoldData);
                numClassifiers = numel(app.opts.Classifierchosed);
                
                % 预分配存储矩阵
                Blockclassifier = nan(numBlocks, numel(feaSelNumPercent), numClassifiers);
                
                % 新增变量存储最佳结果
                bestAccuracy = -inf;
                bestTrainedClassifiers = cell(numBlocks, numClassifiers);
                bestFeatureRatio = 0;
                bestFeatureMask = [];
                bestFeatureScores = [];
                
                % 先执行一次完整特征集的特征选择
                app.opts.selFeatureNumPercent = 1;
                [ResData, ~] = Main_Func_FS(app.opts);
                
                if isempty(ResData)
                    error('Initial feature selection with 100% features returned empty results');
                end
                
                app.ResData = ResData;
                updateMapButtonState(app);
                drawnow;
                
                % 遍历所有特征选择比例
                for iFeaSelPercent = 1:numel(feaSelNumPercent)
                    % 更新进度条
                    progressPct = currentProgress + (stageWeights(3) * (iFeaSelPercent / numel(feaSelNumPercent)));
                    fileProgressDlg.Value = progressPct;
                    fileProgressDlg.Message = sprintf('Processing feature selection ratio: %.2f%%', feaSelNumPercent(iFeaSelPercent) * 100);
                    
                    % 更新特征选择比例
                    app.opts.selFeatureNumPercent = feaSelNumPercent(iFeaSelPercent);
                    
                    % 执行特征选择和分类
                    try
                        [~, ResultsData] = Main_Func_FS(app.opts);
                        
                        % 检查 ResultsData 是否为空
                        if isempty(ResultsData) || ~isfield(ResultsData, 'blockclassifier')
                            error('ResultsData is empty or does not contain blockclassifier field');
                        end
                        
                        % 计算当前平均准确率（跨所有分类器）
                        currentAvgAccuracy = mean(ResultsData.blockclassifier(:), 'omitnan');
                        
                        % 更新最佳结果
                        if currentAvgAccuracy > bestAccuracy
                            bestAccuracy = currentAvgAccuracy;
                            bestTrainedClassifiers = ResultsData.trainedClassifiers;
                            bestFeatureRatio = feaSelNumPercent(iFeaSelPercent);
                            
                            % 获取特征选择分数和掩码
                            if isfield(ResultsData, 'OutputData') && ~isempty(ResultsData.OutputData)
                                bestFeatureScores = ResultsData.OutputData.meanFeaSelScore;
                                selFeatureIdx = ResultsData.OutputData.selFeatureIdxVector;
                                totalFeatures = numel(bestFeatureScores);
                                bestFeatureMask = false(1, totalFeatures);
                                bestFeatureMask(selFeatureIdx) = true;
                            end
                        end
                        
                        % 存储每个分类器的准确率
                        for iClassifier = 1:numClassifiers
                            Blockclassifier(:, iFeaSelPercent, iClassifier) = ResultsData.blockclassifier(:, iClassifier);
                        end
                        
                        % 更新进度信息
                        for iClassifier = 1:numClassifiers
                            classifierName = app.opts.Classifierchosed{iClassifier};
                            meanAccuracy = mean(Blockclassifier(:, iFeaSelPercent, iClassifier), 'omitnan') * 100;
                            updateProgress(app, ['The accuracy of the current feature selection percentage ', ...
                                                 num2str(feaSelNumPercent(iFeaSelPercent) * 100), '% for Classifier: ', ...
                                                 classifierName, ' is ', num2str(meanAccuracy), '%']);
                        end
                        
                    catch ME
                        updateProgress(app, sprintf('Error at feature ratio %.2f%%%: %s', ...
                            feaSelNumPercent(iFeaSelPercent)*100, ME.message));
                        continue;
                    end
                end
                
                % 存储当前文件结果
                currentResult.filename = currentFile;
                currentResult.Blockclassifier = Blockclassifier;
                currentResult.bestAccuracy = bestAccuracy;
                currentResult.bestFeatureRatio = bestFeatureRatio;
                currentResult.classifiers = app.opts.Classifierchosed;
                currentResult.featureRatios = feaSelNumPercent;
                allResults(end+1) = currentResult;
                
                % 显示当前文件结果
                for iClassifier = 1:numClassifiers
                    classifierName = app.opts.Classifierchosed{iClassifier};
                    updateProgress(app, sprintf('File %s - Best Accuracy for %s: %.2f%%% at %.2f%%% feature ratio', ...
                        currentFile, classifierName, bestAccuracy*100, bestFeatureRatio*100));
                end
                
                % 保存当前文件的处理结果
                [~, name, ~] = fileparts(currentFile);
                folderName = [name, '_ProcessingResults'];
                
                if strcmp(fileType, 'folder')
                    folderPath = fullfile(app.SelectedFile, folderName);
                else
                    folderPath = fullfile(fileparts(app.SelectedFile), folderName);
                end
                
                if ~exist(folderPath, 'dir')
                    mkdir(folderPath);
                end

                 app.ImageDisplayPanel.Visible = 'on';
                
                % 清除旧图像
                if ~isvalid(app.ImageUIAxes)
                    error('app.ImageUIAxes is not a valid axes object.');
                end
                cla(app.ImageUIAxes);
                
% 检查 Blockclassifier 是否包含有效值
if any(~isnan(Blockclassifier(:, :, 1)), 'all')
    % 获取分类器数量
    numClassifiers = size(Blockclassifier, 3);
    
    % 创建颜色映射（为不同分类器使用不同颜色）
    colors = lines(numClassifiers); % MATLAB内置的颜色方案
    
    % 准备图例文本
    legendText = cell(1, numClassifiers);
    
    % 绘制所有分类器的准确率曲线
    hold(app.ImageUIAxes, 'on');
    for i = 1:numClassifiers
        avgAccuracy = squeeze(nanmean(Blockclassifier(:, :, i), 1));
        plot(app.ImageUIAxes, feaSelNumPercent * 100, avgAccuracy, ...
            'Color', colors(i,:), 'LineWidth', 2);
        
        % 生成图例文本
        legendText{i} = ['Classifier ' num2str(i)];
    end
    hold(app.ImageUIAxes, 'off');
    
    % 设置图像属性
    xlabel(app.ImageUIAxes, 'Feature selection ratio (%)');
    ylabel(app.ImageUIAxes, 'Classification accuracy');
    title(app.ImageUIAxes, ['Accuracy curves (' num2str(numClassifiers) ' classifiers)']);
    legend(app.ImageUIAxes, legendText, 'Location', 'best');
    grid(app.ImageUIAxes, 'on');
    
    % 保存图像到处理结果文件夹
    try
        % 生成带时间戳的图像文件名
        timestamp = datestr(now, 'yyyymmdd_HHMMSS');
        imgFilename = fullfile(folderPath, ['accuracy_plot_', timestamp, '.png']);
        
        % 保存当前坐标区内容为PNG图像
        exportgraphics(app.ImageUIAxes, imgFilename, 'Resolution', 300);


                savedOpts.nFrequency = app.opts.nFrequency;
                savedOpts.nChannel = app.opts.nChannel;
                savedOpts.type = app.opts.type;
                savedOpts.fs = app.opts.fs;
                savedOpts.FeatureExtractMethod = app.opts.FeatureExtractMethod;
                savedOpts.ClassifierMethod = app.opts.ClassifierMethod;
                savedOpts.Classifierchosed = app.opts.Classifierchosed;
                savedOpts.FeatureSelectionName = app.opts.FeatureSelectionName;
                savedOpts.Windowfunction = app.opts.Windowfunction;
                savedOpts.TimeRngMat = app.opts.TimeRngMat;
                savedOpts.FreqRngMat = app.opts.FreqRngMat;


% 结果数据
%savedOpts.Blockclassifier      = Blockclassifier;
savedOpts.selFeatureNumPercent = bestFeatureRatio;
savedOpts.featureMask          = bestFeatureMask;
savedOpts.featureScores        = bestFeatureScores;
savedOpts.selectedOption       = 'Traverse All Feature Ratios';
savedOpts.filename             = currentFile;
savedOpts.ResData             = app.ResData;
% 压缩分类器模型（使用 compact）
savedOpts.trainedClassifiers   = cellfun(@compact, bestTrainedClassifiers, 'UniformOutput', false);
opts=savedOpts;
% -------------------------------------------------
% 保存到 .mat 文件
% -------------------------------------------------
matFileName = 'processing_results3.mat';
fullMatPath = fullfile(folderPath, matFileName);
save(fullMatPath, 'opts');

updateProgress(app, ['Results for ' currentFile ' saved to: ' folderPath]);
updateProgress(app, ['Accuracy curves saved to: ' imgFilename]);
        
    catch ME
        updateProgress(app, ['Error saving results: ', ME.message]);
    end
else
    error('No valid data found in Blockclassifier for file %s', currentFile);
end

% 完成当前文件处理
fileProgressDlg.Value = 1;
fileProgressDlg.Message = 'File processing completed!';
pause(0.5);
close(fileProgressDlg);
                
            catch ME
                close(fileProgressDlg);
                updateProgress(app, sprintf('Error processing file %s: %s', currentFile, ME.message));
                continue; % 继续处理下一个文件
            end
        end
        
      % ========== 保存汇总结果 ==========
if ~isempty(allResults)
    % 创建汇总Excel文件
    if strcmp(fileType, 'folder')
        excelFileName = fullfile(app.SelectedFile, 'feature_ratio_summary.xlsx');
    else
        excelFileName = fullfile(fileparts(app.SelectedFile), 'feature_ratio_summary.xlsx');
    end
    
    % 准备汇总数据
    summaryHeaders = {'Filename', 'Best Feature Ratio (%)', 'Best Accuracy (%)', ...
        'Classifier', 'Accuracy at 100%', 'Accuracy at 50%', 'Accuracy at 10%'};
    
    % 计算每个文件需要多少行（每个分类器一行）
    numRows = numel(allResults) * numel(allResults(1).classifiers);
    summaryData = cell(numRows+1, length(summaryHeaders));
    summaryData(1,:) = summaryHeaders;
    
    rowIdx = 2;
    for fileIdx = 1:numel(allResults)
        for classifierIdx = 1:numel(allResults(fileIdx).classifiers)
            % 获取当前分类器的所有准确率
            classifierAcc = squeeze(allResults(fileIdx).Blockclassifier(:,:,classifierIdx));
            avgAcc = mean(classifierAcc, 1, 'omitnan'); % 平均所有blocks
            
            % 找到最佳准确率
            [bestAcc, bestIdx] = max(avgAcc);
            bestRatio = allResults(fileIdx).featureRatios(bestIdx)*100;
            
            % 获取特定比例下的准确率
            acc100 = avgAcc(end)*100;    % 100%特征
            [~, idx50] = min(abs(allResults(fileIdx).featureRatios - 0.5));
            acc50 = avgAcc(idx50)*100;   % 50%特征
            [~, idx10] = min(abs(allResults(fileIdx).featureRatios - 0.1));
            acc10 = avgAcc(idx10)*100;   % 10%特征
            
            % 填充数据
            summaryData{rowIdx,1} = allResults(fileIdx).filename;
            summaryData{rowIdx,2} = bestRatio;
            summaryData{rowIdx,3} = bestAcc*100;
            summaryData{rowIdx,4} = allResults(fileIdx).classifiers{classifierIdx};
            summaryData{rowIdx,5} = acc100;
            summaryData{rowIdx,6} = acc50;
            summaryData{rowIdx,7} = acc10;
            
            rowIdx = rowIdx + 1;
        end
    end
    
    % 写入Excel汇总表
    writecell(summaryData, excelFileName, 'Sheet', 'Summary');
    updateProgress(app, ['Summary results saved to: ' excelFileName]);
    
% ========== 保存详细结果 ==========
% 为每个分类器创建单独的工作表
for classifierIdx = 1:numel(allResults(1).classifiers)
    classifierName = allResults(1).classifiers{classifierIdx};
    if length(classifierName) > 31
        classifierName = classifierName(1:31); % Excel工作表名称限制
    end
    
    % 准备详细数据 - 现在改为两列格式
    detailHeaders = {'Feature Ratio (%)', 'Accuracy (%)'};
    
    % 计算总行数（每个文件每个特征比例一行）
    numRatios = numel(allResults(1).featureRatios);
    detailData = cell(numel(allResults) * numRatios + 1, 2);
    detailData(1,:) = detailHeaders;
    
    rowIdx = 2;
    for fileIdx = 1:numel(allResults)
        % 计算当前分类器的平均准确率
        avgAcc = squeeze(mean(allResults(fileIdx).Blockclassifier(:,:,classifierIdx), 1, 'omitnan')) * 100;
        
        % 为每个特征比例添加一行
        for ratioIdx = 1:numel(allResults(fileIdx).featureRatios)
            detailData{rowIdx,1} = allResults(fileIdx).featureRatios(ratioIdx) * 100;
            detailData{rowIdx,2} = avgAcc(ratioIdx);
            rowIdx = rowIdx + 1;
        end
    end
    
    % 创建表格对象并写入
    detailTable = cell2table(detailData(2:end,:), 'VariableNames', detailHeaders);
    
    try
        writetable(detailTable, excelFileName, ...
            'Sheet', classifierName, ...
            'WriteMode', 'overwritesheet');
    catch ME
        % 如果工作表不存在，使用替代方法写入
        if strcmp(ME.identifier, 'MATLAB:table:writetable:SheetNotFound')
            % 先写入一个空表创建工作表
            tempTable = table();
            writetable(tempTable, excelFileName, 'Sheet', classifierName);
            % 然后写入实际数据
            writetable(detailTable, excelFileName, ...
                'Sheet', classifierName, ...
                'WriteMode', 'overwritesheet');
        else
            rethrow(ME);
        end
    end
end

updateProgress(app, 'Detailed results saved to Excel file');
end
        % 所有文件处理完成
        mainProgressDlg.Value = 1;
        mainProgressDlg.Message = 'All files processed successfully!';
        pause(0.5);
        close(mainProgressDlg);
        
    catch ME
        if ishandle(mainProgressDlg)
            close(mainProgressDlg);
        end
        updateProgress(app, ['Final Error: ', ME.message]);
        rethrow(ME);
    end
end
      
   
        end

        % Button pushed function: MapButton
        function MapButtonPushed(app, event)


    updateMapButtonState(app);
    
    % 1. 初始化并检查输入
    updateProgress(app, '=== Start generating the topographic map ===');
    if isempty(app.SelectedFile)
        updateProgress(app, '❌ Error: Please load the data file first');
        return;
    end
    
    % 2. 确定搜索路径
    if isfolder(app.SelectedFile)
        baseFolder = app.SelectedFile;
        updateProgress(app, ['📁 Scan the folder: ' baseFolder]);
        fileType = 'folder';
    else
        baseFolder = fileparts(app.SelectedFile);
        updateProgress(app, ['📂 The directory where the file is located: ' baseFolder]);
        fileType = 'file';
    end
    
    % 3. 查找所有MAT文件（包括子文件夹）
    matFiles = dir(fullfile(baseFolder, '**/*.mat'));  % 递归搜索
    if isempty(matFiles)
        updateProgress(app, '❌ Error: No MAT files were found');
        return;
    end
    
    % 4. 处理每个MAT文件
    processedCount = 0;
    for fileIdx = 1:length(matFiles)
        currentFile = fullfile(matFiles(fileIdx).folder, matFiles(fileIdx).name);
        updateProgress(app, ['🔍 Processing files: ' matFiles(fileIdx).name]);
        
        try
            % 加载数据文件
            data = load(currentFile);

            % 获取基本参数
            Fs = 250; % 默认采样率
            if isfield(data.opts, 'fs')
                Fs = data.opts.fs;
            end
           nChannel = data.opts.nChannel;
%nFrequencyBand = data.opts.nFrequency;
            
 % 获取ListBox中的所有项
    items = app.TimeRangeListsListBox.Items;

    % 初始化一个空矩阵来存储结果
    app.opts.TimeRngMat = [];
    
    % 遍历每个项，将其分割并转换为数字
    for i = 1:length(items)
        % 分割字符串并转换为数字
        numItems = str2num(items{i});
        
        % 将转换后的数字添加到矩阵中
        app.opts.TimeRngMat = [app.opts.TimeRngMat; numItems];
    end

    updateProgress(app, 'The selected time band:');
    % 更新进度框，逐行显示每行的两个数字
    for i = 1:size(app.opts.TimeRngMat, 1)
        % 将每行的两个数字转换为字符串，并用空格分隔
        rowStr = strcat(string(app.opts.TimeRngMat(i, 1)), '-', string(app.opts.TimeRngMat(i, 2)), 'seconds');
        updateProgress(app, rowStr);
    end

    % 对矩阵的每一行进行操作
    for i = 1:size(app.opts.TimeRngMat, 1)
        app.opts.TimeRngMat(i, 1) = app.opts.TimeRngMat(i, 1) * app.opts.fs  + 1;
        app.opts.TimeRngMat(i, 2) = app.opts.TimeRngMat(i, 2) * app.opts.fs;
    end
        
    % 获取ListBox中的所有项
    items2 = app.FrequencyRangeListHzListBox.Items;
    
    % 初始化一个空矩阵来存储结果
    app.opts.FreqRngMat = [];
    
    % 遍历每个项，将其分割并转换为数字
    for i = 1:length(items2)
        % 分割字符串并转换为数字
        numItems2 = str2num(items2{i});
        
        % 将转换后的数字添加到矩阵中
        app.opts.FreqRngMat = [app.opts.FreqRngMat; numItems2];
    end
    
    updateProgress(app, 'The selected frequency band:');
    % 更新进度框，逐行显示每行的两个数字
    for i = 1:size(app.opts.FreqRngMat, 1)
        % 将每行的两个数字转换为字符串，并用空格分隔
        rowStr2 = strcat(string(app.opts.FreqRngMat(i, 1)), '-', string(app.opts.FreqRngMat(i, 2)),'Hz        ');
updateProgress(app, rowStr2);
    end
            
            % 获取block数量
            % 获取block数量

            
updateProgress(app, 'EEG mapping is under way...');
% 获取block数量
% 获取block数量
nBlock = size(data.opts.ResData, 1);

% 计算各block的平均特征得分
for iBlock = 1:nBlock
   
        ResData{iBlock}.DataTestLabel = data.opts.ResData{iBlock}.DataTestLabel;
        ResData{iBlock}.OutputData = data.opts.ResData{iBlock}.OutputData;
   
end
nFrequencyBand = 40;

   app.opts.nFrequency = data.opts.nFrequency;

    totalTime = app.opts.nFrequency/Fs;

    % 根据总时间动态生成 timeStepName 和 timeStepMat
    timeStepName = cell(1, totalTime); % 初始化 timeStepName
    timeStepMat = cell(1, totalTime);  % 初始化 timeStepMat

    for i = 1:totalTime
        % 生成 timeStepName，例如 '0-1s', '1-2s', ...
        timeStepName{i} = sprintf('%d-%ds', i-1, i);
        
        % 生成 timeStepMat，例如 [1 250], [251 500], ...
        timeStepMat{i} = [(i-1)*Fs + 1, i*Fs];
    end

    freqStepName = flip({'δ','θ','α','β','γ'});
    freqStepMat = flip({[1 3]; [4 7]; [8 12]; [13 30]; [30 40]});

    % === 准备绘图数据 ===
    blockMeanFeaSelScore = [];
    CFTMat = zeros(4*Fs, nFrequencyBand, nChannel);
    cntMat = zeros(4*Fs, nFrequencyBand, nChannel);
    CFTCell_Vector = getCFTCell(app.opts);
            
            % 计算各block的平均特征得分
 for iBlock = 1:nBlock
        curBlockMeanFeaSelScore = data.opts.ResData{iBlock}.OutputData.meanFeaSelScore;
        blockMeanFeaSelScore = cat(1, blockMeanFeaSelScore, curBlockMeanFeaSelScore);
    end

    allBlockMeanFeaSelScore = mean(blockMeanFeaSelScore, 1)';


           CFTCell_Vector_Repmat = repmat(CFTCell_Vector', [nChannel 1]);
    CFTCell_Vector_Repmat = CFTCell_Vector_Repmat(:);
    allBlockChannelIndex = repmat((1:nChannel)', [1 numel(CFTCell_Vector)]);
    allBlockChannelIndex = allBlockChannelIndex(:);

    for iCFTCell = 1:numel(CFTCell_Vector_Repmat)
        CFTCell_Vector_Repmat{iCFTCell}.SelCh = allBlockChannelIndex(iCFTCell);
    end

    for iFeature = 1:numel(CFTCell_Vector_Repmat)
        curTBand = CFTCell_Vector_Repmat{iFeature}.TBand(1):CFTCell_Vector_Repmat{iFeature}.TBand(2);
        curFBand = ceil(CFTCell_Vector_Repmat{iFeature}.FBand(1)):ceil(CFTCell_Vector_Repmat{iFeature}.FBand(2));
        curChannelGroup = CFTCell_Vector_Repmat{iFeature}.SelCh;

        cntMat(curTBand, curFBand, curChannelGroup) = cntMat(curTBand, curFBand, curChannelGroup) + 1;
        CFTMat(curTBand, curFBand, curChannelGroup) = CFTMat(curTBand, curFBand, curChannelGroup) + allBlockMeanFeaSelScore(iFeature);
    end

    CFTMeanMat = CFTMat ./ cntMat;
    maxAllCFTMat = max(squeeze(mean(CFTMeanMat, [1 2], 'omitnan')));
    minAllCFTMat = min(squeeze(mean(CFTMeanMat, [1 2], 'omitnan')));
             % === 清空原有内容 ===
    delete(findall(app.ImageDisplayPanel, 'Type', 'axes'));
    delete(findall(app.ImageDisplayPanel, 'Type', 'uilabel', 'Tag', 'TopoplotTitle'));
    delete(findall(app.ImageDisplayPanel, 'Type', 'uilabel', 'Tag', 'TopoplotTimeLabel'));
    delete(findall(app.ImageDisplayPanel, 'Type', 'uilabel', 'Tag', 'TopoplotFreqLabel'));

    app.ImageDisplayPanel.Visible = 'on';
            
                % === 子图排布参数 ===
    nRow = numel(freqStepMat);
    nCol = numel(timeStepMat);

    % === 布局设置 ===
    startX = 20;
    startY = 25;   % ✅ 往下移，避免遮挡标题
    hSpacing = 4;
    vSpacing = 4;

    panelPos = app.ImageDisplayPanel.Position;
    panelWidth = panelPos(3);
    panelHeight = panelPos(4);

    usableWidth = panelWidth - 2*startX - (nCol - 1) * hSpacing;
    usableHeight = panelHeight - startY - 60 - (nRow - 1) * vSpacing;  % 60 for time labels

    axHeight = floor(usableHeight / nRow);
    axWidth = floor(usableWidth / nCol);
    axSize = min(axWidth, axHeight);
    axWidth = axSize + 30;   % 适度放大
    axHeight = axSize + 5;  % 椭圆形

    % === 添加标题 ===
    titleLabel = uilabel(app.ImageDisplayPanel);
    titleLabel.Text = 'EEG Topographic Map';
    titleLabel.FontSize = 14;
    titleLabel.FontWeight = 'bold';
    titleLabel.HorizontalAlignment = 'center';
    titleLabel.WordWrap = 'on';
    titleLabel.Position = [0, panelHeight - 40, panelWidth, 25];  % ✅ 稍微往下留白
    titleLabel.Tag = 'TopoplotTitle';

    % === 绘图主循环 ===
    for iF = 1:nRow
        for iT = 1:nCol
            ax = axes('Parent', app.ImageDisplayPanel);
            ax.Units = 'pixels';
            ax.Position = [
                startX + (iT-1)*(axWidth + hSpacing), ...
                startY + (nRow - iF)*(axHeight + vSpacing), ...
                axWidth, axHeight];

            ax.XColor = 'none'; ax.YColor = 'none';
            ax.XTick = []; ax.YTick = []; ax.Box = 'off';

            curSubCFTMeanMat = CFTMeanMat(...
                timeStepMat{iT}(1):timeStepMat{iT}(2), ...
                freqStepMat{iF}(1):freqStepMat{iF}(2), :);

            axes(ax);
            topoplotEEG_forApp(squeeze(mean(curSubCFTMeanMat, [1 2], 'omitnan')), ...
                'channel_locations_59ch.txt', ...
                'electrodes', 'off', ...
                'maplimits', [minAllCFTMat maxAllCFTMat], ...
                'parent', ax);

            % 添加频率标签（左侧）
            if iT == 1
                uilabel(app.ImageDisplayPanel, ...
                    'Text', freqStepName{iF}, ...
                    'FontSize', 10, ...
                    'HorizontalAlignment', 'right', ...
                    'Tag', 'TopoplotFreqLabel', ...
                    'Position', [
                        startX - 35, ...
                        startY + (nRow - iF)*(axHeight + vSpacing) + axHeight/2 - 8, ...
                        30, 20]);
            end
        end
    end

    % === 时间标签（统一底部） ===
    for iT = 1:nCol
        uilabel(app.ImageDisplayPanel, ...
            'Text', timeStepName{iT}, ...
            'FontSize', 10, ...
            'HorizontalAlignment', 'center', ...
            'Tag', 'TopoplotTimeLabel', ...
            'Position', [
                startX + (iT-1)*(axWidth + hSpacing), ...
                startY - 25, ...
                axWidth, 20]);
    end
            
            % 保存结果
            [~, name, ~] = fileparts(currentFile);
            folderName = [name, '_EEGTopoResults'];
            
            if strcmp(fileType, 'folder')
                folderPath = fullfile(matFiles(fileIdx).folder, folderName);
            else
                folderPath = fullfile(fileparts(currentFile), folderName);
            end
            
            if ~exist(folderPath, 'dir')
                mkdir(folderPath);
            end
            
            timestamp = datestr(now, 'yyyymmdd_HHMMSS');
            topoFilename = fullfile(folderPath, ['topo_plot_', timestamp, '.png']);
            drawnow; pause(0.5);
            
            if exist('exportgraphics', 'file')
                exportgraphics(app.ImageDisplayPanel, topoFilename, ...
                    'Resolution', 300, 'BackgroundColor', 'white');
            else
                fig = ancestor(app.ImageDisplayPanel, 'figure');
                panelPos = getpixelposition(app.ImageDisplayPanel, true);
                figPos = getpixelposition(fig);
                captureRect = [panelPos(1), figPos(4)-panelPos(2)-panelPos(4)+1, panelPos(3), panelPos(4)];
                frame = getframe(fig, captureRect);
                imwrite(frame.cdata, topoFilename, 'PNG', 'ResolutionUnit', 'inch', ...
                    'XResolution', 300, 'YResolution', 300);
            end
            
            updateProgress(app, ['✅ Saved successfully: ' topoFilename]);
            processedCount = processedCount + 1;
            
        catch err
            updateProgress(app, ['❌ Failed processing: ' err.message]);
        end
    end
    
    % % 5. 最终状态报告
    % if processedCount > 0
    %     updateProgress(app, sprintf('=== 完成！成功处理 %d/%d 个文件 ===', processedCount, length(matFiles)));
    % else
    %     updateProgress(app, '❌ 错误：未能处理任何文件');
    % 
    % 
    % end


        end

        % Value changed function: FeatureSelectionOptionDropDown
        function FeatureSelectionOptionDropDownValueChanged(app, event)
    % Get the selected value of FeatureSelectionType
    selectedType = app.FeatureSelectionOptionDropDown.Value;

    % Enable or disable FeatureSelectionRate and FeatureSelectionMethod based on the selection
    if strcmp(selectedType, 'No Feature Selection')
        app.FeatureSelectionRateEditField.Enable = 'off';
        app.FeatureSelectionMethodDropDown.Enable = 'off';

    elseif strcmp(selectedType, 'Select Certain Features')
        app.FeatureSelectionRateEditField.Enable = 'on';
        app.FeatureSelectionMethodDropDown.Enable = 'on';

    elseif strcmp(selectedType, 'Traverse All Feature Ratios')
        app.FeatureSelectionRateEditField.Enable = 'off';  % 禁用输入框
        app.FeatureSelectionMethodDropDown.Enable = 'on';

    end
    
        end

        % Callback function
        function ExtractionButtonPushed(app, event)

    % 弹出文件夹选择对话框
    folderPath = uigetdir('Select the Feature Extraction Method folder');
    
    % 检查用户是否取消了操作
    if isempty(folderPath)
      %  disp('User selected Cancel');
        updateProgress(app, 'User selected Cancel');
        return; % 退出函数
    end
    
    % 检查文件夹是否存在
    if ~isfolder(folderPath)
        error('Selected folder does not exist.');
    end

    % 获取文件夹中的所有 .m 文件
    fileNames = dir(fullfile(folderPath, '*.m'));
    fileNames = {fileNames.name}';

    % 提取函数名称（去掉 .m 后缀）
    functionNames = cellfun(@(x) strrep(x, '.m', ''), fileNames, 'UniformOutput', false);

    % 生成映射表
    app.FeatureMapping = containers.Map(functionNames, functionNames);

    % 更新下拉菜单选项
    app.FeatureExtractionMethodDropDown_2.Items = functionNames;

%     % 打印映射表内容以进行调试
%     disp('Generated Feature Mapping Table:');
%     keys = app.FeatureMapping.keys;
%     for i = 1:length(keys)
%         key = keys{i};
%         value = app.FeatureMapping(key);
%         disp(['Key: ', key, ', Value: ', value]);
%     end

    % 更新进度信息
    updateProgress(app, ['Feature extraction methods updated from folder: ', folderPath]);


        end

        % Callback function
        function ClassifierButtonPushed(app, event)
% 
%     % 弹出文件夹选择对话框
%     folderPath = uigetdir('Select the Classifier folder');
%     
%     % 检查用户是否取消了操作
%     if isempty(folderPath)
%         disp('User selected Cancel');
%         updateProgress(app, 'User selected Cancel');
%         return; % 退出函数
%     end
%     
%     % 检查文件夹是否存在
%     if ~isfolder(folderPath)
%         error('Selected folder does not exist.');
%     end
% 
%     % 获取文件夹中的所有 .m 文件
%     fileNames = dir(fullfile(folderPath, '*.m'));
%     fileNames = {fileNames.name}';
% 
%     % 提取函数名称（去掉 .m 后缀）
%     classifierNames = cellfun(@(x) strrep(x, '.m', ''), fileNames, 'UniformOutput', false);
% 
%     % 生成映射表
%     app.ClassifierMapping = containers.Map(classifierNames, classifierNames);
% 
%     % 更新下拉菜单选项
%     app.ClassifierDropDown.Items = classifierNames;
% 
% %     % 打印映射表内容以进行调试
% %     disp('Generated Classifier Mapping Table:');
% %     keys = app.ClassifierMapping.keys;
% %     for i = 1:length(keys)
% %         key = keys{i};
% %         value = app.ClassifierMapping(key);
% %         disp(['Key: ', key, ', Value: ', value]);
% %     end
% 
%     % 更新进度信息
%     updateProgress(app, ['Classifier methods updated from folder: ', folderPath]);

        end

        % Callback function
        function SelectionButtonPushed(app, event)

    % 弹出文件夹选择对话框
    folderPath = uigetdir('Select the Feature Selection Method folder');
    
    % 检查用户是否取消了操作
    if isempty(folderPath)
      %  disp('User selected Cancel');
        updateProgress(app, 'User selected Cancel');
        return; % 退出函数
    end
    
    % 检查文件夹是否存在
    if ~isfolder(folderPath)
        error('Selected folder does not exist.');
    end

    % 获取文件夹中的所有 .m 文件
    fileNames = dir(fullfile(folderPath, '*.m'));
    fileNames = {fileNames.name}';

    % 提取函数名称（去掉 .m 后缀）
    featureSelectionNames = cellfun(@(x) strrep(x, '.m', ''), fileNames, 'UniformOutput', false);

    % 生成映射表
    app.FeatureSelectedMethodMapping  = containers.Map(featureSelectionNames, featureSelectionNames);

    % 更新下拉菜单选项
    app.FeatureSelectionMethodDropDown.Items = featureSelectionNames;

%     % 打印映射表内容以进行调试
%     disp('Generated Feature Selection Mapping Table:');
%     keys = app.FeatureSelectedMethodMapping .keys;
%     for i = 1:length(keys)
%         key = keys{i};
%         value = app.FeatureSelectedMethodMapping (key);
%         disp(['Key: ', key, ', Value: ', value]);
%     end

    % 更新进度信息
    updateProgress(app, ['Feature selection methods updated from folder: ', folderPath]);

        end

        % Callback function
        function ButtonPushed(app, event)
        if ~isempty(app.FeatureSelectionMethodDropDown.Value)
            app.opts.selectedFeatureSelectionName = app.FeatureSelectionMethodDropDown.Value;
        else
            app.opts.selectedFeatureSelectionName = ''; % 如果没有选择任何方法，则设置为空字符串
        end
      %  disp(app.opts.selectedFeatureSelectionName)
        end

        % Value changed function: FeatureExtractionMethodDropDown_2
        function FeatureExtractionMethodDropDown_2ValueChanged(app, event)
        % 调用函数更新下拉框选项
            updateFeatureExtractionDropDown(app);   
  

        end

        % Value changed function: FeatureSelectionMethodDropDown
        function FeatureSelectionMethodDropDownValueChanged(app, event)
            updateFeatureSelectionDropDown(app);  
        end

        % Value changed function: ClassifierDropDown
        function ClassifierDropDownValueChanged(app, event)
            updateClassifierDropDown(app);  
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)

            app.LoadPanel.Visible = 'off';

        end

        % Button pushed function: LoadmatButton
        function LoadmatButtonPushed(app, event)

    [f, p] = uigetfile('*.mat', 'Select MAT File');
    if isequal(f,0)
        uialert(app.UIFigure, 'File selection cancelled.', 'Cancelled');
        return;
    end
    app.SelectedFile = fullfile(p, f);
    app.SelectedType = 'mat';
    app.CrossValidationFoldEditField.Enable = 'off';  % .mat 不需要折数输入

    updateProgress(app, "📁 File selected:\nPath: " + app.SelectedFile);


        end

        % Button pushed function: LoadzipButton
        function LoadzipButtonPushed(app, event)

    [f, p] = uigetfile('*.zip', 'Select ZIP File');
    if isequal(f,0)
        uialert(app.UIFigure, 'File selection cancelled.', 'Cancelled');
        return;
    end
    app.SelectedFile = fullfile(p, f);
    app.SelectedType = 'zip';
    app.CrossValidationFoldEditField.Enable = 'on';

    % ✅ 显示选择文件信息
    updateProgress(app, "📁 File selected:\nPath: " + app.SelectedFile);

        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)

    % ✅ Check if file/folder is selected
    if isempty(app.SelectedFile)
        uialert(app.UIFigure, 'Please select a file or folder first.', 'No File/Folder');
        return;
    end

    % ✅ Read and validate parameters
    fs = app.FsEditField.Value;
    if ~isnumeric(fs) || isnan(fs)
        uialert(app.UIFigure, 'Invalid sampling rate. Please enter a number (e.g., 1000).', 'Invalid Parameter');
        return;
    end

    % 获取控件中的值
fold_num = str2double(app.CrossValidationFoldEditField.Value);
notch_freq = str2double(app.NotchFreqDropDown.Value);
bp_order = str2double(app.OrderDropDown.Value);
f_pass = app.FpassHzEditField.Value; % 获取 FpassHzEditField 的值
f_stop = app.FstopHzEditField.Value; % 获取 FstopHzEditField 的值

% 获取 CheckBox 的选中状态
use_notch = app.NotchCheckBox.Value;
use_bp = app.BPCheckBox.Value;

% 检查所有参数是否有效
if any(isnan([fs, fold_num, notch_freq, bp_order, f_pass, f_stop]))
    uialert(app.UIFigure, 'One or more parameters are invalid. Please check sampling rate, fold number, and filter settings.', 'Invalid Parameter');
    return;
end

% ✅ Show progress: start
updateProgress(app, "🚀 Starting data processing...");
drawnow;

    try
        % === Process based on selected type ===
        if strcmp(app.SelectedType, 'mat')
            % === MAT file processing ===
            updateProgress(app, "📂 Loading MAT file...");
            loaded = load(app.SelectedFile);
            if isfield(loaded, 'foldData') && isfield(loaded, 'foldLabel')
                updateProgress(app, "🧠 Processing MAT EEG data...");
                [foldData, foldLabel] = process_from_mat( ...
                    loaded.foldData, loaded.foldLabel, ...
                    fs, notch_freq, bp_order, use_notch, use_bp,f_pass,f_stop);
            else
                uialert(app.UIFigure, 'MAT file must contain foldData and foldLabel variables.', 'Format Error');
                updateProgress(app, "❌ Missing foldData or foldLabel in MAT file. Aborted.");
                return;
            end

        elseif strcmp(app.SelectedType, 'zip')
            % === ZIP file processing ===
            updateProgress(app, "📦 Unzipping ZIP file...");
            temp_dir = tempname;
            mkdir(temp_dir);
            unzip(app.SelectedFile, temp_dir);

            % Find EEG folder and label file
            eeg_txt_folder = '';
            label_file = '';
            files = dir(temp_dir);
            for i = 1:length(files)
                if files(i).isdir && ~startsWith(files(i).name, '.')
                    eeg_txt_folder = fullfile(temp_dir, files(i).name);
                elseif endsWith(files(i).name, '.xlsx')
                    label_file = fullfile(temp_dir, files(i).name);
                end
            end

            if isempty(eeg_txt_folder) || isempty(label_file)
                uialert(app.UIFigure, 'ZIP file must contain a .txt folder and a .xlsx label file.', 'Invalid Structure');
                updateProgress(app, "❌ Invalid ZIP structure. Aborted.");
                return;
            end

            updateProgress(app, "🧠 Processing ZIP EEG data...");
            [foldData, foldLabel] = process_from_zip( ...
                eeg_txt_folder, label_file, fs, notch_freq, bp_order, fold_num, use_notch, use_bp,f_pass,f_stop);

        elseif strcmp(app.SelectedType, 'folder')
            % === Folder processing ===
            updateProgress(app, "📂 Loading files from folder...");
            files = dir(fullfile(app.SelectedFile, '*.mat')); % 获取文件夹中的.mat文件
            zip_files = dir(fullfile(app.SelectedFile, '*.zip')); % 获取文件夹中的.zip文件
            all_files = [files; zip_files]; % 合并.mat和.zip文件列表

            if isempty(all_files)
                uialert(app.UIFigure, 'No .mat or .zip files found in the selected folder.', 'No Files');
                updateProgress(app, "❌ No .mat or .zip files found. Aborted.");
                return;
            end

            % 初始化数据结构
            foldData = [];
            foldLabel = [];

            for i = 1:length(all_files)
                file_path = fullfile(app.SelectedFile, all_files(i).name);
                updateProgress(app, "📂 Processing file: " + all_files(i).name);

                if endsWith(all_files(i).name, '.mat')
                    % 处理.mat文件
                    loaded = load(file_path);
                    if isfield(loaded, 'foldData') && isfield(loaded, 'foldLabel')
                        [tempData, tempLabel] = process_from_mat( ...
                            loaded.foldData, loaded.foldLabel, ...
                            fs, notch_freq, bp_order, use_notch, use_bp,f_pass,f_stop);
                    else
                        uialert(app.UIFigure, 'MAT file must contain foldData and foldLabel variables.', 'Format Error');
                        updateProgress(app, "❌ Missing foldData or foldLabel in MAT file. Skipping file.");
                        continue;
                    end
                elseif endsWith(all_files(i).name, '.zip')
                    % 处理.zip文件
                    temp_dir = tempname;
                    mkdir(temp_dir);
                    unzip(file_path, temp_dir);

                    % Find EEG folder and label file
                    eeg_txt_folder = '';
                    label_file = '';
                    files_in_zip = dir(temp_dir);
                    for j = 1:length(files_in_zip)
                        if files_in_zip(j).isdir && ~startsWith(files_in_zip(j).name, '.')
                            eeg_txt_folder = fullfile(temp_dir, files_in_zip(j).name);
                        elseif endsWith(files_in_zip(j).name, '.xlsx')
                            label_file = fullfile(temp_dir, files_in_zip(j).name);
                        end
                    end

                    if isempty(eeg_txt_folder) || isempty(label_file)
                        uialert(app.UIFigure, 'ZIP file must contain a .txt folder and a .xlsx label file.', 'Invalid Structure');
                        updateProgress(app, "❌ Invalid ZIP structure. Skipping file.");
                        continue;
                    end

                    [tempData, tempLabel] = process_from_zip( ...
                        eeg_txt_folder, label_file, fs, notch_freq, bp_order, fold_num, use_notch, use_bp,f_pass,f_stop);
                end

                % 将当前文件的数据和标签追加到总数据中
                foldData = [foldData; tempData];
                foldLabel = [foldLabel; tempLabel];
            end

        else
            uialert(app.UIFigure, 'Unknown file type selected.', 'Error');
            updateProgress(app, "❌ Unknown file type. Aborted.");
            return;
        end

        % ✅ Save and show success
        app.LoadedData.foldData = foldData;
        app.LoadedData.foldLabel = foldLabel;
        app.StartButton.Enable = 'on';

        updateProgress(app, "✅ Data successfully processed and loaded.");
        uiprogressdlg(app.UIFigure, ...
            'Title', 'Success', ...
            'Message', '✅ Data processed and loaded successfully.', ...
            'Icon', 'success', ...
            'Value', 1);

    catch ME
        updateProgress(app, "❌ Error occurred: " + ME.message);
        uialert(app.UIFigure, ME.message, 'Processing Error');
    end

        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
    app.ImageDisplayPanel.Visible = 'off';

        end

        % Button pushed function: AddFeature
        function AddFeatureButtonPushed(app, event)
     
    % 获取下拉框当前选中的值
    selectedValue = app.FeatureExtractionMethodDropDown_2.Value;
    
    % 判断是否已经存在于 SelectedFeatures 中
    if ismember(selectedValue, app.SelectedFeatures)
        % 如果存在，提示重复添加错误
        updateProgress(app, 'The selected feature already exists. Duplicate addition is not allowed.');
    else
        % 如果不存在，添加到 SelectedFeatures 和 FeatureExtractionMethodListBox 中
        app.SelectedFeatures{end+1} = selectedValue;
        app.FeatureExtractionMethodListBox.Items = app.SelectedFeatures;
    end
        end

        % Button pushed function: MoreButton
        function MoreButtonPushed(app, event)
            app.FeatureExtractionMethodsPanel.Visible = 'on';
        end

        % Button pushed function: DeleteButton_3
        function DeleteButton_3Pushed(app, event)

    % 获取 listbox 中选中的项
    selectedItems = app.FeatureExtractionMethodListBox.Value;
    
    % 检查是否有选中的项
    if isempty(selectedItems)
        updateProgress(app, 'No item was selected, deletion failed');
        return;
    end
    
    % 找到选中项在 listbox 中的索引
    [~, idx] = ismember(selectedItems, app.FeatureExtractionMethodListBox.Items);
    
    % 删除选中的项
    app.FeatureExtractionMethodListBox.Items(idx) = [];
    
    % 更新 SelectedFeatures
    app.SelectedFeatures = app.FeatureExtractionMethodListBox.Items;
    
    % 在进度框中显示删除成功的消息
    updateProgress(app, 'Deletion was successful');

        end

        % Button pushed function: BackButton_3
        function BackButton_3Pushed(app, event)
           app.FeatureExtractionMethodsPanel.Visible = 'off';     
        end

        % Button pushed function: AddButton_3
        function AddButton_3Pushed(app, event)
    % % 获取下拉框当前选中的值
    % selectedValue1 = app.AllClassifiersListBox.Value;
    % 
    % % 判断是否已经存在于 SelectedFeatures 中
    % if ismember(selectedValue1, app.SelectedClassifiers)
    %     % 如果存在，提示重复添加错误
    %     updateProgress(app, 'The selected classifier already exists. Duplicate addition is not allowed.');
    % else
    %     % 如果不存在，添加到 SelectedFeatures 和 FeatureExtractionMethodListBox 中
    %     app.SelectedClassifiers{end+1} = selectedValue1;
    %     app.AllClassifiersListBox.Items = app.SelectedClassifiers;
    % end
  
    % 获取下拉框当前选中的值
    selectedValue1 = app.ClassifierDropDown.Value;
    
    % 判断是否已经存在于 SelectedClassifiers 中
    if ismember(selectedValue1, app.SelectedClassifiers)
        % 如果存在，提示重复添加错误
        updateProgress(app, 'The selected classifier already exists. Duplicate addition is not allowed.');
    else
        % 如果不存在，添加到 SelectedClassifiers
        app.SelectedClassifiers{end+1} = selectedValue1;
        
        % 确保 SelectedClassifiers 是字符串数组
        app.SelectedClassifiers = cellstr(app.SelectedClassifiers);
        
        % 更新 AllClassifiersListBox 的 Items
        app.AllClassifiersListBox.Items = app.SelectedClassifiers;
    end


        end

        % Button pushed function: DeleteButton_4
        function DeleteButton_4Pushed(app, event)
            
    % 获取 listbox 中选中的项
    selectedItems1 = app.AllClassifiersListBox.Value;
    
    % 检查是否有选中的项
    if isempty(selectedItems1)
        updateProgress(app, 'No item was selected, deletion failed');
        return;
    end
    
    % 找到选中项在 listbox 中的索引
    [~, idx] = ismember(selectedItems1, app.AllClassifiersListBox.Items);
    
    % 删除选中的项
    app.AllClassifiersListBox.Items(idx) = [];
    
    % 更新 SelectedFeatures
    app.SelectedClassifiers = app.AllClassifiersListBox.Items;
    
    % 在进度框中显示删除成功的消息
    updateProgress(app, 'Deletion was successful');
        end

        % Button pushed function: BackButton_4
        function BackButton_4Pushed(app, event)
             app.ClassifiersPanel.Visible = 'off';  
        end

        % Button pushed function: MoreButton_2
        function MoreButton_2Pushed(app, event)
            app.ClassifiersPanel.Visible = 'on';
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)

    % 1. 确定要导出的文件夹路径
    if isfolder(app.SelectedFile)
        sourceFolder = app.SelectedFile;
        excludeFile  = '';  % 文件夹模式，无需排除文件
    else
        [sourceFolder, baseName, ext] = fileparts(app.SelectedFile);
        excludeFile = [baseName ext];  % 需要排除的文件名
    end

    % 2. 检查文件夹是否存在
    if ~exist(sourceFolder, 'dir')
        uialert(app.UIFigure, ...
            sprintf('Folder not found:\n%s', sourceFolder), ...
            'Export Error', 'Icon', 'error');
        return;
    end

    % 3. 设置默认压缩包名称
    [~, folderName] = fileparts(sourceFolder);
    defaultName = sprintf('%s_Results_%s.zip', ...
        folderName, ...
        datestr(now, 'yyyymmdd'));

    % 4. 让用户选择保存位置
    [filename, pathname] = uiputfile( ...
        {'*.zip', 'ZIP Archive (*.zip)'}, ...
        'Save Results Archive As', ...
        defaultName);

    if isequal(filename, 0) || isequal(pathname, 0)
        return; % 用户取消操作
    end

    zipFile = fullfile(pathname, filename);

    % 5. 创建临时目录，复制除排除文件外的所有内容
    tempDir = tempname;
    mkdir(tempDir);
    try
        % 复制整个文件夹（保留目录结构）
        copyfile(sourceFolder, tempDir, 'f');

        % 如果是文件模式，删除临时目录中的排除文件
        if ~isempty(excludeFile)
            fileToDelete = fullfile(tempDir, excludeFile);
            if exist(fileToDelete, 'file')
                delete(fileToDelete);
            end
        end

        % 压缩临时目录下的内容
        zip(zipFile, fullfile(tempDir, '*'));

        % 显示成功消息
        msg = sprintf('Results successfully exported to:\n%s', zipFile);
        uialert(app.UIFigure, msg, 'Export Complete', ...
               'Icon', 'success');

    catch ME
        uialert(app.UIFigure, ...
            sprintf('Export failed:\nError: %s', ME.message), ...
            'Export Error', 'Icon', 'error');
    finally
        % 清理临时目录
        if exist(tempDir, 'dir')
            rmdir(tempDir, 's');
        end
    end

        end

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed(app, event)

    % 打开文件夹选择对话框
    folderPath = uigetdir('Select Folder Containing .mat or .zip Files');
    if isequal(folderPath, 0)
        uialert(app.UIFigure, 'Folder selection cancelled.', 'Cancelled');
        return;
    end

    % 保存选中的文件夹路径
    app.SelectedFile = folderPath;
    app.SelectedType = 'folder';

    % 检查文件夹内是否有 .zip 文件
    zipFiles = dir(fullfile(folderPath, '*.zip'));
    if ~isempty(zipFiles)
        % 如果有 .zip 文件，启用 FoldEditField（允许输入折数）
        app.CrossValidationFoldEditField.Enable = 'on';
        updateProgress(app, "📁 Folder selected (contains .zip files):\nPath: " + app.SelectedFile);
    else
        % 如果没有 .zip 文件，禁用 FoldEditField
        app.CrossValidationFoldEditField.Enable = 'off';
        updateProgress(app, "📁 Folder selected (no .zip files):\nPath: " + app.SelectedFile);
    end


        end

        % Value changed function: BPCheckBox
        function BPCheckBoxValueChanged(app, event)

    value = app.BPCheckBox.Value;
    
    % 根据 CheckBox 的选中状态设置其他控件的可用性
    if value % 如果 CheckBox 被选中
        app.OrderDropDown.Enable = 'on'; % 启用 OrderDropDown
        app.FstopHzEditField.Enable = 'on'; % 启用 FstopHzEditField
        app.FpassHzEditField.Enable = 'on'; % 启用 FpassHzEditField
    else % 如果 CheckBox 未被选中
        app.OrderDropDown.Enable = 'off'; % 禁用 OrderDropDown
        app.FstopHzEditField.Enable = 'off'; % 禁用 FstopHzEditField
        app.FpassHzEditField.Enable = 'off'; % 禁用 FpassHzEditField
    end

        end

        % Value changed function: NotchCheckBox
        function NotchCheckBoxValueChanged(app, event)
            value = app.NotchCheckBox.Value;
            if value % 如果 CheckBox 被选中
                app.NotchFreqDropDown.Enable = 'on'; % 启用 OrderDropDown
            else % 如果 CheckBox 未被选中
                app.NotchFreqDropDown.Enable = 'off'; % 禁用 OrderDropDown
               
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InformationDisplayTextAreaLabel = uilabel(app.UIFigure);
            app.InformationDisplayTextAreaLabel.Text = 'Information Display';
            app.InformationDisplayTextArea = uitextarea(app.UIFigure);
            app.InformationDisplayTextArea.Editable = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TrainingSettingsTab = uitab(app.TabGroup);
            app.TrainingSettingsTab.Title = 'Training Settings';
            app.FeatureExtractionMethodDropDown_2Label = uilabel(app.TrainingSettingsTab);
            app.FeatureExtractionMethodDropDown_2Label.Text = 'Feature Extraction Method';
            app.FeatureExtractionMethodDropDown_2 = uidropdown(app.TrainingSettingsTab);
            app.FeatureExtractionMethodDropDown_2.Items = {};
            app.FeatureExtractionMethodDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FeatureExtractionMethodDropDown_2ValueChanged, true);
            app.FeatureExtractionMethodDropDown_2.Value = {};
            app.FeatureSelectionOptionDropDownLabel = uilabel(app.TrainingSettingsTab);
            app.FeatureSelectionOptionDropDownLabel.Text = 'Feature Selection Option';
            app.FeatureSelectionOptionDropDown = uidropdown(app.TrainingSettingsTab);
            app.FeatureSelectionOptionDropDown.Items = {'No Feature Selection', 'Select Certain Features', 'Traverse All Feature Ratios'};
            app.FeatureSelectionOptionDropDown.ValueChangedFcn = createCallbackFcn(app, @FeatureSelectionOptionDropDownValueChanged, true);
            app.FeatureSelectionOptionDropDown.Value = 'No Feature Selection';
            app.FeatureSelectionMethodLabel = uilabel(app.TrainingSettingsTab);
            app.FeatureSelectionMethodLabel.Enable = 'off';
            app.FeatureSelectionMethodLabel.Text = 'Feature Selection Method';
            app.FeatureSelectionMethodDropDown = uidropdown(app.TrainingSettingsTab);
            app.FeatureSelectionMethodDropDown.Items = {};
            app.FeatureSelectionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @FeatureSelectionMethodDropDownValueChanged, true);
            app.FeatureSelectionMethodDropDown.Enable = 'off';
            app.FeatureSelectionMethodDropDown.Value = {};
            app.ClassifierDropDownLabel = uilabel(app.TrainingSettingsTab);
            app.ClassifierDropDownLabel.Text = 'Classifier';
            app.ClassifierDropDown = uidropdown(app.TrainingSettingsTab);
            app.ClassifierDropDown.Items = {};
            app.ClassifierDropDown.ValueChangedFcn = createCallbackFcn(app, @ClassifierDropDownValueChanged, true);
            app.ClassifierDropDown.Value = {};
            app.FeatureSelectionRateLabel = uilabel(app.TrainingSettingsTab);
            app.FeatureSelectionRateLabel.Enable = 'off';
            app.FeatureSelectionRateLabel.Text = 'Feature Selection Rate(%)';
            app.FeatureSelectionRateEditField = uieditfield(app.TrainingSettingsTab, 'numeric');
            app.FeatureSelectionRateEditField.Limits = [1 100];
            app.FeatureSelectionRateEditField.Enable = 'off';
            app.FeatureSelectionRateEditField.Value = 10;
            app.ParallelComputingSwitchLabel = uilabel(app.TrainingSettingsTab);
            app.ParallelComputingSwitchLabel.Text = 'Parallel Computing';
            app.ParallelComputingSwitch = uiswitch(app.TrainingSettingsTab, 'slider');
            app.MoreButton = uibutton(app.TrainingSettingsTab, 'push');
            app.MoreButton.ButtonPushedFcn = createCallbackFcn(app, @MoreButtonPushed, true);
            app.MoreButton.Text = 'More';
            app.MoreButton_2 = uibutton(app.TrainingSettingsTab, 'push');
            app.MoreButton_2.ButtonPushedFcn = createCallbackFcn(app, @MoreButton_2Pushed, true);
            app.MoreButton_2.Text = 'More';
            app.TimeFrequencySegmentationStrategiesTab = uitab(app.TabGroup);
            app.TimeFrequencySegmentationStrategiesTab.Title = 'Time-Frequency Segmentation Strategies';
            app.TimeRangeListsLabel = uilabel(app.TimeFrequencySegmentationStrategiesTab);
            app.TimeRangeListsLabel.Text = {'Time'; 'Range '; 'List (s)'};
            app.TimeRangeListsListBox = uilistbox(app.TimeFrequencySegmentationStrategiesTab);
            app.TimeRangeListsListBox.Items = {'0 2', '0 1', '1 2', '0.5 1.5'};
            app.TimeRangeListsListBox.Value = '0 2';
            app.FrequencyRangeListHzLabel = uilabel(app.TimeFrequencySegmentationStrategiesTab);
            app.FrequencyRangeListHzLabel.Text = {'Frequency '; 'Range'; 'List (Hz)'};
            app.FrequencyRangeListHzListBox = uilistbox(app.TimeFrequencySegmentationStrategiesTab);
            app.FrequencyRangeListHzListBox.Items = {'0.5  4', ' 4    8', ' 8   12', '12  16', '16  20', '20  24', '24  28', '28  32', '32  36', '36  40', ' 2    6', ' 6   10', '10  14', '14  18', '18  22', '22  26', '26  30', '30  34', '34  38', '0.5   8', ' 8   16', '16  24', '24  32', '32  40', ' 4   12', '12  20', '20  28', '28  36', '0.5 16', '16  40', ' 8   24', '24  40', '0.5 40'};
            app.FrequencyRangeListHzListBox.Value = '0.5  4';
            app.TimeRangesEditFieldLabel = uilabel(app.TimeFrequencySegmentationStrategiesTab);
            app.TimeRangesEditFieldLabel.Text = 'Time Range(s)';
            app.TimeRangesEditField = uieditfield(app.TimeFrequencySegmentationStrategiesTab, 'text');
            app.AddButton = uibutton(app.TimeFrequencySegmentationStrategiesTab, 'state');
            app.AddButton.ValueChangedFcn = createCallbackFcn(app, @AddButtonValueChanged, true);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.TimeFrequencySegmentationStrategiesTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.FrequencyRangeHzEditFieldLabel = uilabel(app.TimeFrequencySegmentationStrategiesTab);
            app.FrequencyRangeHzEditFieldLabel.Text = 'Frequency Range(Hz)';
            app.FrequencyRangeHzEditField = uieditfield(app.TimeFrequencySegmentationStrategiesTab, 'text');
            app.AddButton_2 = uibutton(app.TimeFrequencySegmentationStrategiesTab, 'push');
            app.AddButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddButton_2Pushed, true);
            app.AddButton_2.Text = 'Add';
            app.DeleteButton_2 = uibutton(app.TimeFrequencySegmentationStrategiesTab, 'push');
            app.DeleteButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteButton_2Pushed, true);
            app.DeleteButton_2.Text = 'Delete';
            app.WindowFunctionDropDownLabel = uilabel(app.TimeFrequencySegmentationStrategiesTab);
            app.WindowFunctionDropDownLabel.Text = 'Window Function';
            app.WindowFunctionDropDown = uidropdown(app.TimeFrequencySegmentationStrategiesTab);
            app.WindowFunctionDropDown.Items = {'Rectangular', 'Hamming', 'Chebwin', 'Blackman', 'Blackmanharris', 'Hanning', 'Gausswin'};
            app.WindowFunctionDropDown.Value = 'Rectangular';
            app.FilterOrderEditFieldLabel = uilabel(app.TimeFrequencySegmentationStrategiesTab);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.FilterOrderEditField = uieditfield(app.TimeFrequencySegmentationStrategiesTab, 'numeric');
            app.FilterOrderEditField.Value = 4;
            app.MapButton = uibutton(app.UIFigure, 'push');
            app.MapButton.ButtonPushedFcn = createCallbackFcn(app, @MapButtonPushed, true);
            app.MapButton.Enable = 'off';
            app.MapButton.Text = 'Map';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed2, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.FeatureExtractionMethodsPanel = uipanel(app.UIFigure);
            app.FeatureExtractionMethodsPanel.Title = 'Feature Extraction Methods';
            app.FeatureExtractionMethodsPanel.Visible = 'off';
            app.FeatureExtractionMethodsLabel = uilabel(app.FeatureExtractionMethodsPanel);
            app.FeatureExtractionMethodsLabel.Text = 'All Methods:';
            app.FeatureExtractionMethodListBox = uilistbox(app.FeatureExtractionMethodsPanel);
            app.FeatureExtractionMethodListBox.Items = {};
            app.FeatureExtractionMethodListBox.Value = {};
            app.AddFeature = uibutton(app.FeatureExtractionMethodsPanel, 'push');
            app.AddFeature.ButtonPushedFcn = createCallbackFcn(app, @AddFeatureButtonPushed, true);
            app.AddFeature.Text = 'Add';
            app.DeleteButton_3 = uibutton(app.FeatureExtractionMethodsPanel, 'push');
            app.DeleteButton_3.ButtonPushedFcn = createCallbackFcn(app, @DeleteButton_3Pushed, true);
            app.DeleteButton_3.Text = 'Delete';
            app.BackButton_3 = uibutton(app.FeatureExtractionMethodsPanel, 'push');
            app.BackButton_3.ButtonPushedFcn = createCallbackFcn(app, @BackButton_3Pushed, true);
            app.BackButton_3.Text = 'Back';
            app.LoadPanel = uipanel(app.UIFigure);
            app.LoadPanel.Title = 'Load File';
            app.LoadPanel.Visible = 'off';
            app.BackButton = uibutton(app.LoadPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.LoadmatButton = uibutton(app.LoadPanel, 'push');
            app.LoadmatButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmatButtonPushed, true);
            app.LoadmatButton.Text = 'Load .mat';
            app.LoadzipButton = uibutton(app.LoadPanel, 'push');
            app.LoadzipButton.ButtonPushedFcn = createCallbackFcn(app, @LoadzipButtonPushed, true);
            app.LoadzipButton.Text = 'Load .zip';
            app.StartButton_2 = uibutton(app.LoadPanel, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.NotchCheckBox = uicheckbox(app.LoadPanel);
            app.NotchCheckBox.ValueChangedFcn = createCallbackFcn(app, @NotchCheckBoxValueChanged, true);
            app.NotchCheckBox.Text = '';
            app.NotchFrequencyLabel = uilabel(app.LoadPanel);
            app.NotchFrequencyLabel.Enable = 'off';
            app.NotchFrequencyLabel.Text = 'Frequency(Hz):';
            app.NotchFreqDropDown = uidropdown(app.LoadPanel);
            app.NotchFreqDropDown.Items = {'50', '60'};
            app.NotchFreqDropDown.Enable = 'off';
            app.NotchFreqDropDown.Value = '50';
            app.BPCheckBox = uicheckbox(app.LoadPanel);
            app.BPCheckBox.ValueChangedFcn = createCallbackFcn(app, @BPCheckBoxValueChanged, true);
            app.BPCheckBox.Text = '';
            app.OrderDropDownLabel = uilabel(app.LoadPanel);
            app.OrderDropDownLabel.Enable = 'off';
            app.OrderDropDownLabel.Text = ' Order:';
            app.OrderDropDown = uidropdown(app.LoadPanel);
            app.OrderDropDown.Items = {'2', '3'};
            app.OrderDropDown.Enable = 'off';
            app.OrderDropDown.Value = '2';
            app.OriginalSamplingFrequencyHzLabel = uilabel(app.LoadPanel);
            app.OriginalSamplingFrequencyHzLabel.Text = 'Original Sampling Frequency(Hz):';
            app.FsEditField = uieditfield(app.LoadPanel, 'numeric');
            app.FsEditField.Limits = [50 5000];
            app.FsEditField.ValueDisplayFormat = '%.0f';
            app.FsEditField.Value = 250;
            app.CrossValidationFoldEditFieldLabel = uilabel(app.LoadPanel);
            app.CrossValidationFoldEditFieldLabel.Text = 'Cross Validation Fold:';
            app.CrossValidationFoldEditField = uieditfield(app.LoadPanel, 'text');
            app.CrossValidationFoldEditField.Value = '5';
            app.LoadFolderButton = uibutton(app.LoadPanel, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Text = 'Load Folder';
            app.NotchFilterLabel = uilabel(app.LoadPanel);
            app.NotchFilterLabel.Text = 'Notch Filter';
            app.BandpassFilterLabel = uilabel(app.LoadPanel);
            app.BandpassFilterLabel.Text = 'Bandpass Filter';
            app.FpassHzEditFieldLabel = uilabel(app.LoadPanel);
            app.FpassHzEditFieldLabel.Enable = 'off';
            app.FpassHzEditFieldLabel.Text = 'Fpass(Hz):';
            app.FpassHzEditField = uieditfield(app.LoadPanel, 'numeric');
            app.FpassHzEditField.Enable = 'off';
            app.FpassHzEditField.Value = 0.5;
            app.FstopHzEditFieldLabel = uilabel(app.LoadPanel);
            app.FstopHzEditFieldLabel.Enable = 'off';
            app.FstopHzEditFieldLabel.Text = 'Fstop(Hz):';
            app.FstopHzEditField = uieditfield(app.LoadPanel, 'numeric');
            app.FstopHzEditField.Enable = 'off';
            app.FstopHzEditField.Value = 45;
            app.ClassifiersPanel = uipanel(app.UIFigure);
            app.ClassifiersPanel.Title = 'Classifiers';
            app.ClassifiersPanel.Visible = 'off';
            app.AllClassifiersLabel = uilabel(app.ClassifiersPanel);
            app.AllClassifiersLabel.Text = 'All Classifiers:';
            app.AllClassifiersListBox = uilistbox(app.ClassifiersPanel);
            app.AllClassifiersListBox.Items = {};
            app.AllClassifiersListBox.Value = {};
            app.AddButton_3 = uibutton(app.ClassifiersPanel, 'push');
            app.AddButton_3.ButtonPushedFcn = createCallbackFcn(app, @AddButton_3Pushed, true);
            app.AddButton_3.Text = 'Add';
            app.DeleteButton_4 = uibutton(app.ClassifiersPanel, 'push');
            app.DeleteButton_4.ButtonPushedFcn = createCallbackFcn(app, @DeleteButton_4Pushed, true);
            app.DeleteButton_4.Text = 'Delete';
            app.BackButton_4 = uibutton(app.ClassifiersPanel, 'push');
            app.BackButton_4.ButtonPushedFcn = createCallbackFcn(app, @BackButton_4Pushed, true);
            app.BackButton_4.Text = 'Back';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ImageDisplayPanel = uipanel(app.UIFigure);
            app.ImageDisplayPanel.Title = 'Image Display';
            app.ImageDisplayPanel.Visible = 'off';
            app.ImageUIAxes = uiaxes(app.ImageDisplayPanel);
            title(app.ImageUIAxes, 'Title')
            xlabel(app.ImageUIAxes, 'X')
            ylabel(app.ImageUIAxes, 'Y')
            zlabel(app.ImageUIAxes, 'Z')
            app.BackButton_2 = uibutton(app.ImageDisplayPanel, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEG_signal_feature_processing_software

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end